classdef TargetWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TargetWindowFig  matlab.ui.Figure
        DirectionAxes    matlab.ui.control.UIAxes
        GaugeAxesL       matlab.ui.control.UIAxes
        GaugeAxesR       matlab.ui.control.UIAxes
        CommandLabel     matlab.ui.control.Label
    end

 % depends on vecmath class for methods: hom, unhom, clip, and changeScale
    
    properties (Access = public)
        
        num_gauges = 0                      % 0: no gauges 1: single gauge 2: both gauges
        % gauge parameters
        gauge_limits = [0 100]              % 
        % gauge params
        gauge_width = 50                    % pixels
        gauge_height
        % gauge patch objects
        gLPO                                % patch object handle to Left gauge
        gRPO                                % patch object handle to Right gauge
        gauge_vertices                      % 
        % pyramid patch object
        FBPyPO                              % patch object handle to feedback pyramid
        TPyPO                               % patch object handle to target pyramid
        TTracePO                            % patch object handle to target trace
        % pyramid parameters        
        py_vertices                         % 
        py_faces                            %  

        
    end
    
    properties (Access = private)
        
        
        % GAUGE DEFAULTS        
        gauge_bgcolor = 'w'                 % gauge background color
        gauge_edgecolor = 'k'               % gauge edge color
        gauge_fillcolor = 'r'               % gauge default fill color
        
        % PYRAMID DEFAULTS
        % Target Colors
        Tbasecolor = [0 75 255]/255        % dark blue
        Tsidecolor = [200 0 100]/255        % dark pink
        Tneckcolor = [0 75 255]/255        % dark blue
        % Feedback Colors
        FBbasecolor = [50 200 225]/255      % light blue 
        FBsidecolor = [255 153 204]/255     % light pink
        FBneckcolor = [50 200 225]/255      % light blue
        % Transparencies
        FBTransparency = 0.7                % FB Pyramid patch object FaceAlpha transparency value [0 1] (0 = transparent)
        TargetTransparency = 0.7            % Target Pyramid patch object FaceAlpha transparency value [0 1] (0 = transparent)
        % Base pyramid shape parameters
        py_width0 = 25                      % pyramid base width
        py_ht_ratio = 2                     % base ht = py_ht_ratio * py_width0
        py_nose_ratio = 5                   % pyramid nose length = py_nose_ratio * py_width0
        
        % DirectionAxes
        AxLim = [-100 100;...               % DirectionAxes limits (x,y,z) by (min max)
                 -100 100;...
                 -100 100]
        XAxLim = [-300 300]                 % actual X axis limits so nose doesn't get clipped
    end
    
    methods (Access = public)
        
        function updateCommand(app, txt, color)
            
            app.CommandLabel.Text = txt;
            app.CommandLabel.FontColor = color;            
        
        end
        
        function updateCommandVisibile(app, bool)
            
            switch bool
                case 0
                    onoff = 'off';
                case 1
                    onoff = 'on';
            end
            
            app.CommandLabel.Visible = onoff;
        end
        
        function updateGaugeValue(app, newval)

            % clip and scale value                   
            newval = vecmath.clip(newval, app.gauge_limits(1) + 0.01, app.gauge_limits(2));
            %newval = vecmath.changeScale(newval, app.gauge_limits, [0 app.gauge_height]);           

            % change Vertices property of gauge patch to update height
            if ~isempty(app.gRPO)                
                app.gRPO.Vertices(3:4,2) = [newval;newval];                
            end
            if ~isempty(app.gLPO)                
                app.gLPO.Vertices(3:4,2) = [newval;newval];                
            end
        end
    
        function changeGaugeFillColor(app, color)
            % color: color 
            if ~isempty(app.gRPO)            
                app.gRPO.FaceColor = color;
            end
            if ~isempty(app.gLPO)            
                app.gLPO.FaceColor = color;
            end            
        end
    
        function changeGaugeLimits(app, val_lims)
            % value_limits: [value_min value_max]
            app.gauge_limits = val_lims;
            
            % change y-axis limits
            if ~isempty(app.gRPO)
                app.GaugeAxesR.YLim = val_lims;
            end
            if ~isempty(app.gLPO)
                app.GaugeAxesL.YLim = val_lims;
            end       
            
        end
    
        function axlim = getAxLimits(app)
            % DirectionAxes x, y, and z limits
            % axlim: (3x2) (x y z) by (min max)
            
            axlim = app.AxLim;
            
        end
    
        function setAxLimits(app, axlim)
            % DirectionAxes x, y, and z limits
            % axlim: (3x2) (x y z) by (min max)
           
            app.DirectionAxes.XLim = axlim(1,:);
            app.DirectionAxes.YLim = axlim(2,:);
            app.DirectionAxes.ZLim = axlim(3,:);
            
            app.AxLim = axlim;

        end
    
        function changeCameraPosition(app, viewpoint)
            
            switch viewpoint
                case {'back',"back",0}
                    app.DirectionAxes.CameraPosition = [-200, 0, 0];
                    app.DirectionAxes.ZDir = 'normal';
                case {'mirror',"mirror",1}
                    app.DirectionAxes.CameraPosition = [200, 0, 0];
                    app.DirectionAxes.ZDir = 'reverse';
                otherwise
                    warning('Invalid input, options are back and mirror')
            end
            
        end
    
        function pydims = getPyramidDims(app)
            % pydims: [nose length, base height, base width] (x, y, z) 
            
            w = app.py_width0;                    % (z) pyramid base width                  
            ht = app.py_ht_ratio * w;             % (y) base height
            d = app.py_nose_ratio * w;            % (x) nose length
            
            pydims = [d, ht, w]; 
            
        end
    
        function setPyramidDims(app, pydims)
            % pydims: [nose length, base height, base width]
            % still need to recreate any existing pyramids to actually update
            
            app.py_width0 = pydims(3);            % (z) pyramid base width                  
            app.py_ht_ratio = pydims(2)/w;        % (y) base height
            app.py_nose_ratio = pydims(1)/w;      % (x) nose length            
            
        end
    
        function createFBPyramid(app)
            [app.FBPyPO, app.py_vertices] = app.initializePyramid(); 
            app.changeFBPyramidTransparency(app.FBTransparency);
            app.changePyramidFaceColors(app.FBPyPO, app.FBbasecolor, app.FBsidecolor, app.FBneckcolor);  
        end
        
        function createTargetPyramid(app)
            app.TPyPO = app.initializePyramid();   
            app.changeTPyramidTransparency(app.TargetTransparency);
            app.changePyramidFaceColors(app.TPyPO, app.Tbasecolor, app.Tsidecolor, app.Tneckcolor);  
        end
    
        function targetPyramidVisible(app, onoff)
            if ~isempty(app.TPyPO)
                app.TPyPO.Visible = onoff;
            end
        end
    
        function FBPyramidVisible(app, onoff)
            if ~isempty(app.FBPyPO)
                app.FBPyPO.Visible = onoff;
            end
        end
    
        function changeTPyramidTransparency(app, alpha)
            if ~isempty(app.TPyPO)
                app.TPyPO.FaceAlpha = alpha;
            end
            app.TargetTransparency = alpha;
        end
    
        function changeFBPyramidTransparency(app, alpha)
            if ~isempty(app.FBPyPO)
                app.FBPyPO.FaceAlpha = alpha;
            end
            app.FBTransparency = alpha;
        end
    
        function updateFBPyramid(app, Tmat)
            
            % transform these points from original vertices
            vp = app.py_vertices(1:8,:);            
            
            % update patch object vertices
            app.FBPyPO.Vertices(1:8,:) = app.transformVertices(vp, Tmat);           
            
        end
    
        function updateTargetPyramid(app, Tmat)
            
            % transform these points from original vertices
            vp = app.py_vertices(1:8,:);            
            
            % update patch object vertices
            app.TPyPO.Vertices(1:8,:) = app.transformVertices(vp, Tmat);           
            
        end
    
    end
    
    methods (Access = private)
        
        function v = changeFillHeight(~, v, ht)
            % based on rectangle vertices created in
            % createRectangle(x0,y0,xm,ym). Changing only the height of the
            % rectangle. 
            v(3:4,2) = [ht; ht];
        end
    
        function [po, v] = createGaugeFillPO(~, ax, fill_color)
            
            % Coordinates
            x0 = 0;
            y0 = 0;
            xm = ax.Position(3);
            ym = 1; % default
            
            % Vertices (x, y)
            v = [  x0      y0; ... % 1 % lower left
                   xm      y0; ... % 2 % lower right
                   xm      ym; ... % 3 % upper right
                   x0      ym];    % 4 % upper left
            
            % Single Face
            f = [ 1 2 3 4 ];       % face                      
            
            po = patch('Parent', ax...
                ,'Vertices', v, 'Faces', f...
                ,'FaceColor', fill_color, 'EdgeColor', 'none');            
            
        end

    
        function [po, v] = initializePyramid(app)
            
            % get y axis limits
            yl = app.DirectionAxes.YLim;
            zl = app.DirectionAxes.ZLim;
            
            % sizes of zero pyramid
            px = app.py_nose_ratio * app.py_width0;     % nose length
            py = app.py_ht_ratio * app.py_width0;       % head height
            pz = app.py_width0;                         % head width
            nz = app.py_width0 / 4;                     % neck width 
            ty =  yl(1) + ((yl(2) - yl(1))/2) / 4;      % torso height
            tz = ((zl(2) - zl(1))/2);                   % torso width           
        
            %pyramid vertices (8) (need to transpose for hom, unhom, and computations)
            v =     [    0    py    -pz/2  ;... %1 % head pyramid
                         0    py     pz/2  ;... %2
                         0    0      pz/2  ;... %3
                         0    0     -pz/2  ;... %4
                         px   py/2    0    ;... %5      % tip of pyramid (nose)
                         0    py/2    0    ;... %6      % center of base
                         0    0     -nz    ;... %7 % neck
                         0    0      nz    ;... %8
                         0    ty     nz    ;... %9 % do not transform from here on
                         0    ty    -nz    ;... %10
                         0    ty    -tz/2  ;... %11 %torso
                         0    ty     tz/2  ;... %12
                         0    yl(1)  tz/2  ;... %13
                         0    yl(1) -tz/2 ];... %14                
                         
                   
            % pyramid faces (8)
            f = [   1  2  3  4  ; ...% base
                    1  5  2  1  ; ...% side top
                    2  5  3  2  ; ...% side right
                    3  5  4  3  ; ...% side bottom
                    4  5  1  4  ; ...% side left 
                    7  8  9  10 ; ...% neck
                   11 12 13  14 ];...% torso
            
            % create patch 
            po = patch('Parent', app.DirectionAxes...
                ,'Vertices', v, 'Faces', f...
                ,'FaceColor', 'flat'); 
            
        end
        
        function c = changePyramidFaceColors(~, po, base, side, neck)
            
            c = [ repmat(base,   1,1); ... % base color
                  repmat(side,   4,1); ... % side color
                  repmat(neck,   2,1) ];   % neck and torso
              
            po.FaceVertexCData = c;
            
        end
            
            
        function v = transformVertices(~, v0, Tmat)
            % v0: (n x 3) vertices of shape in (x y z) coords 
            % Tmat : (4x4) homogenous transformation matrix 
            % v : transformed coordinates

            v = vecmath.unhom(Tmat*vecmath.hom(v0'))';

        end
    
    
        function updatePyramid(app, po, Tmat)

            % transform these points from original vertices
            vp = app.py_vertices(1:8,:);            
            
            % update patch object vertices
            po.Vertices(1:8,:) = app.transformVertices(vp, Tmat);           
            
        end


        function initializeAxes(app)
            
            % get current figure dimensions
            figdim = app.TargetWindowFig.Position(3:4);            

            % make gauge axes invisible
            app.GaugeAxesL.Visible = 'on';
            app.GaugeAxesR.Visible = 'on';                   
            
            % Gauge dimensions
            gaxdim = [app.gauge_width, figdim(2)];
            
            % GaugeAxesL                 
            gLaxpos = [0 0]; %lower left corner
            app.GaugeAxesR.Position = [gLaxpos gaxdim];
            
            % DirectionAxes
            daxdim = [figdim(1)-2*app.gauge_width, figdim(2)];
            daxpos = [gLaxpos(1)+gaxdim(1), 0]; % to right of L gauge
            app.DirectionAxes.Position = [daxpos daxdim];
            % make sure Data Aspect Ratio is still equal
            app.DirectionAxes.DataAspectRatio = [1 1 1]; 
            
            % GaugeAxesR
            gRaxpos = [daxpos(1)+daxdim(1), 0]; % to right of DirectionAxes
            app.GaugeAxesR.Position = [gRaxpos gaxdim];
            
            % create patch objects
            [app.gRPO, app.gauge_vertices] = app.createGaugeFillPO(app.GaugeAxesR, app.gauge_fillcolor);
            app.gLPO = app.createGaugeFillPO(app.GaugeAxesL, app.gauge_fillcolor);
            
            % for convenience
            app.gauge_width = app.GaugeAxesR.Position(3);
            app.gauge_height = app.GaugeAxesR.Position(4);
            
            % set axes limits
            app.DirectionAxes.XLim = app.XAxLim;
            app.DirectionAxes.YLim = app.AxLim(2,:);
            app.DirectionAxes.ZLim = app.AxLim(3,:);   
            
            % gauge axes limits
            app.GaugeAxesL.XLim = [0 app.gauge_width];
            app.GaugeAxesR.XLim = [0 app.gauge_width];
            app.GaugeAxesL.YLim = app.gauge_limits;
            app.GaugeAxesR.YLim = app.gauge_limits;           
            
        end
        
    end
    
    methods (Static)
        % basic math functions 
        
        % Transform data to new scale based on [min max] 
        function new_data = changeScale(data, iR, oR)
            % Inputs:       iR: (nx2) input Range n rows of [min max] 
            %               oR: (nx2) output Range n rows of [min max]
            %               data: (nx1) vector of data in input range (corresponding to [min max] in nth row)
            % Outputs:      new_data: (nx1) vector of data in output range
            % 
                       
            new_data = (oR(:,2)-oR(:,1))./(iR(:,2)-iR(:,1)).*(data - iR(:,1)) + oR(:,1);    
        end
        
        function pts = hom(pts) 
            % adds additional ROW of ones
            npts = size(pts,2);
            pts = [pts; ones(1,npts)];
        end
        
        function pts = unhom(pts)
            % removes last ROW
            pts = pts(1:3,:);
        end
        
        function y = clip(x,lb,ub)
            y = min( max(x, lb), ub);
        end 
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % initialize number of gauges and size of axes to maximize space in figure
            app.initializeAxes();              
            
        end

        % Close request function: TargetWindowFig
        function TargetWindowFigCloseRequest(app, event)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TargetWindowFig = uifigure('Visible', 'off');
            app.TargetWindowFig.Name = 'UI Figure';
            app.TargetWindowFig.CloseRequestFcn = createCallbackFcn(app, @TargetWindowFigCloseRequest, true);
            app.DirectionAxes = uiaxes(app.TargetWindowFig);
            app.DirectionAxes.XLim = [-300 300];
            app.DirectionAxes.YLim = [-100 100];
            app.DirectionAxes.ZLim = [-100 100];
            app.DirectionAxes.XAxisLocation = 'origin';
            app.DirectionAxes.XTick = [];
            app.DirectionAxes.XTickLabel = {};
            app.DirectionAxes.YAxisLocation = 'origin';
            app.DirectionAxes.YTick = [];
            app.DirectionAxes.YTickLabel = {};
            app.DirectionAxes.ZTick = [];
            app.DirectionAxes.ZTickLabel = '';
            app.GaugeAxesL = uiaxes(app.TargetWindowFig);
            app.GaugeAxesL.XLim = [0 100];
            app.GaugeAxesL.YLim = [0 30];
            app.GaugeAxesL.XTick = [0 100];
            app.GaugeAxesL.XTickLabel = {'0'; '100'};
            app.GaugeAxesL.YTick = [0 100];
            app.GaugeAxesL.YTickLabel = {'-'; '100'};
            app.GaugeAxesR = uiaxes(app.TargetWindowFig);
            app.GaugeAxesR.XLim = [0 100];
            app.GaugeAxesR.YLim = [0 30];
            app.GaugeAxesR.XTick = [0 100];
            app.GaugeAxesR.XTickLabel = {'0'; '100'};
            app.GaugeAxesR.YTick = [0 100];
            app.GaugeAxesR.YTickLabel = {'-'; '100'};
            app.CommandLabel = uilabel(app.TargetWindowFig);
            app.CommandLabel.Text = 'Relax';
            % Show the figure after all components are created
            app.TargetWindowFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TargetWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TargetWindowFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TargetWindowFig)
        end
    end
end