classdef neckGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TargetDAQUI                     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        DAQConfigurationPanel           matlab.ui.container.Panel
        DAQDeviceDropDownLabel          matlab.ui.control.Label
        DAQDeviceDropDown               matlab.ui.control.DropDown
        ScanRateLabel                   matlab.ui.control.Label
        scanRateField                   matlab.ui.control.NumericEditField
        SampleTimeLabel                 matlab.ui.control.Label
        sampleTimeField                 matlab.ui.control.NumericEditField
        InitializeDAQButton             matlab.ui.control.Button
        SubjectInformationPanel         matlab.ui.container.Panel
        ParticipantIDEditFieldLabel     matlab.ui.control.Label
        ParticipantIDField              matlab.ui.control.EditField
        ProtocolEditFieldLabel          matlab.ui.control.Label
        ProtocolField                   matlab.ui.control.EditField
        NotesTextAreaLabel              matlab.ui.control.Label
        NotesField                      matlab.ui.control.TextArea
        EMGOptionsPanel                 matlab.ui.container.Panel
        CollectEMGCheckBox              matlab.ui.control.CheckBox
        ofEMGsSpinnerLabel              matlab.ui.control.Label
        nEMGSpinner                     matlab.ui.control.Spinner
        FileOptionsPanel                matlab.ui.container.Panel
        CurrentDirectoryButton          matlab.ui.control.Button
        CurrentDirectoryTextArea        matlab.ui.control.TextArea
        basefilenameLabel               matlab.ui.control.Label
        basefilenameEditField           matlab.ui.control.EditField
        SaveLoadPanel                   matlab.ui.container.Panel
        SaveConfigButton                matlab.ui.control.Button
        LoadConfigButton                matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        LoadTestDirectionsButton        matlab.ui.control.Button
        TransformTab                    matlab.ui.container.Tab
        BonyLandmarkTableLabel          matlab.ui.control.Label
        HeadFrameTable                  matlab.ui.control.Table
        XForwardYVerticalupZRightLabel  matlab.ui.control.Label
        WrenchFrameDropDownLabel        matlab.ui.control.Label
        WrenchFrameDropDown             matlab.ui.control.DropDown
        SetTargetTab                    matlab.ui.container.Tab
        TargetSettingsPanel             matlab.ui.container.Panel
        TargetDirectionLabel            matlab.ui.control.Label
        TargetTable                     matlab.ui.control.Table
        DirIdxSpinnerLabel              matlab.ui.control.Label
        DirIdxSpinner                   matlab.ui.control.Spinner
        DirNameField                    matlab.ui.control.EditField
        OpenTargetWindowButton          matlab.ui.control.StateButton
        ZeroLoadCellButton              matlab.ui.control.Button
        WrenchRangesPanel               matlab.ui.container.Panel
        maxFSpinnerLabel                matlab.ui.control.Label
        maxFSpinner                     matlab.ui.control.Spinner
        maxMSpinnerLabel                matlab.ui.control.Label
        maxMSpinner                     matlab.ui.control.Spinner
        NLabel                          matlab.ui.control.Label
        NmLabel                         matlab.ui.control.Label
        TargetModeDropDownLabel         matlab.ui.control.Label
        TargetModeDropDown              matlab.ui.control.DropDown
        ActiveComponentsPanel           matlab.ui.container.Panel
        FxCheckBox                      matlab.ui.control.CheckBox
        FyCheckBox                      matlab.ui.control.CheckBox
        FzCheckBox                      matlab.ui.control.CheckBox
        MxCheckBox                      matlab.ui.control.CheckBox
        MyCheckBox                      matlab.ui.control.CheckBox
        MzCheckBox                      matlab.ui.control.CheckBox
        TargetWindowOptionsPanel        matlab.ui.container.Panel
        ShowTargetCheckBox              matlab.ui.control.CheckBox
        ShowFeedbackCheckBox            matlab.ui.control.CheckBox
        ViewDropDownLabel               matlab.ui.control.Label
        ViewDropDown                    matlab.ui.control.DropDown
        TargetTransparencySpinnerLabel  matlab.ui.control.Label
        TargetTransparencySpinner       matlab.ui.control.Spinner
        FBTransparencySpinnerLabel      matlab.ui.control.Label
        FBTransparencySpinner           matlab.ui.control.Spinner
        DirectionMatchingErrorPanel     matlab.ui.container.Panel
        DirectionErrorWindowLabel       matlab.ui.control.Label
        DirMatchErrorEditField          matlab.ui.control.NumericEditField
        RatioErrorWindowLabel           matlab.ui.control.Label
        RatioErrorSpinner               matlab.ui.control.Spinner
        ErrorsPanel                     matlab.ui.container.Panel
        MerrEditFieldLabel              matlab.ui.control.Label
        MerrEditField                   matlab.ui.control.NumericEditField
        FerrEditFieldLabel              matlab.ui.control.Label
        FerrEditField                   matlab.ui.control.NumericEditField
        RerrEditFieldLabel              matlab.ui.control.Label
        RerrEditField                   matlab.ui.control.NumericEditField
        WrenchPlotsTab                  matlab.ui.container.Tab
        WrenchPlotGrid                  matlab.ui.container.GridLayout
        EMGPlotsTab                     matlab.ui.container.Tab
        EMGPlotGrid                     matlab.ui.container.GridLayout
        GaugesTab                       matlab.ui.container.Tab
        WMagGaugeLabel                  matlab.ui.control.Label
        WMagGauge                       matlab.ui.control.LinearGauge
        WMagEditField                   matlab.ui.control.NumericEditField
        ForcesPanel                     matlab.ui.container.Panel
        FMagEditField                   matlab.ui.control.NumericEditField
        FxMagEditField                  matlab.ui.control.NumericEditField
        FyMagEditField                  matlab.ui.control.NumericEditField
        FzMagEditField                  matlab.ui.control.NumericEditField
        FMagGauge_2Label                matlab.ui.control.Label
        FMagGauge                       matlab.ui.control.LinearGauge
        FxGaugeLabel                    matlab.ui.control.Label
        FxGauge                         matlab.ui.control.LinearGauge
        FzGaugeLabel                    matlab.ui.control.Label
        FzGauge                         matlab.ui.control.LinearGauge
        ForwardLabel                    matlab.ui.control.Label
        BackwardLabel                   matlab.ui.control.Label
        RightLabel                      matlab.ui.control.Label
        UpLabel                         matlab.ui.control.Label
        DownLabel                       matlab.ui.control.Label
        FyGaugeLabel                    matlab.ui.control.Label
        FyGauge                         matlab.ui.control.LinearGauge
        LeftLabel                       matlab.ui.control.Label
        MomentsPanel                    matlab.ui.container.Panel
        MxMagEditField                  matlab.ui.control.NumericEditField
        MyMagEditField                  matlab.ui.control.NumericEditField
        MzMagEditField                  matlab.ui.control.NumericEditField
        MMagEditField                   matlab.ui.control.NumericEditField
        MMagGaugeLabel                  matlab.ui.control.Label
        MMagGauge                       matlab.ui.control.LinearGauge
        MzGaugeLabel                    matlab.ui.control.Label
        MzGauge                         matlab.ui.control.LinearGauge
        MxGaugeLabel                    matlab.ui.control.Label
        MxGauge                         matlab.ui.control.LinearGauge
        MyGaugeLabel                    matlab.ui.control.Label
        MyGauge                         matlab.ui.control.LinearGauge
        TurnLeftLabel                   matlab.ui.control.Label
        TurnRightLabel                  matlab.ui.control.Label
        SBLeftLabel                     matlab.ui.control.Label
        SBRightLabel                    matlab.ui.control.Label
        LookUpLabel                     matlab.ui.control.Label
        LookDownLabel                   matlab.ui.control.Label
        Tab                             matlab.ui.container.Tab
        StatusTextTextAreaLabel         matlab.ui.control.Label
        StatusText                      matlab.ui.control.TextArea
        StopButton                      matlab.ui.control.Button
        TrialEditFieldLabel             matlab.ui.control.Label
        TrialNumberField                matlab.ui.control.NumericEditField
        StatusLabel                     matlab.ui.control.Label
        AcquireDataButton               matlab.ui.control.Button
    end

    % FRAMES
    % Head body coordinate frame is oriented so that: 
    %       x points forward towards the nose, 
    %       y is vertical up
    %       z is to the right
    % 
    % World frame is at the center of the load cell oriented: 
    %       x points forward (LC z)
    %       y is vertical (LC Rz(-45deg))
    %       z points to the subjects right
    % 
    % 
    %%
    % UNITS
    % load cell data is in lbs and in units
    % this app converts the data to N and m units in DataAvailable Callback
    % function. Any length should be input in meters, forces in N, and
    % moments in Nm. This is also how data will be reported except in the
    % raw data fifo. 
    
    properties (Access = private)         
        
        % Target Window Figure Handles
        FigH                                            % handle to TargetWindow object        
                     
        % Target Window Modes
        TargetMode                                      % Target visual mode from dropdown (1: Channel 2: Orientation and Mag 3: ...)
        FBFrameMode                                     % Frame of FB Wrench from dropdown (1: Head 2: LoadCell 3: World)
        
        % Target Setting Values
        TargetValues = zeros(6,1);                      % 1x6 array of target direction [fx fy fz mx my mz]    
              
        % Range for visual feedback
        VisLim                                          % (6x2) (x,y,z,tx,ty,tz) x (min max) set with axlim2vislim()    
        
        % Wrench Range
        WRanges = [ -100 100; ...
                    -100 100; ...
                    -100 100; ...
                    -15 15; ...
                    -15 15; ...
                    -15 15 ]                            % (6x2) (Fx,Fy,Fz,Mx,My,Mz) by (min max) in N or Nm
                
        % 
        fscale                                          % 
        mscale                                          % 
        
        py_scale_range = [1.75 0.25]                    % x-axis linear scaling of pyramid
        py_rot_range = deg2rad(45)                      % max rotation in radians
        
        % 
        
        
        % DAQ Configuration 
        DevicesInfo                                     % array of device information 
        daqDevice = 'Dev1'                              % default device name 
        sRate = 1000                                    % default sampling rate (Hz)
        sTime = 10                                      % default sampling length (sec)
        WsRate = 10                                     % sample rate for wrench (DataAvailableCallback 
                                                        % executes every sRate/WsRate samples
        
        % 
        DAQSession = []                                 % daq session from s = daqinit()
        
        % Listener Handle
        DataAvailableLH                                 % listener handle for DAQ session 'DataAvailable' event
        
        % Transformation Matrices 
        CalMatrix = eye(6)                              % Load cell calibration matrix 
        LC2Head = eye(6)                                % Transform matrix from Load Cell
        LCZeroOffset = zeros(6,1)                       % wrench values from last time zero button pressed 
        
        FBCompsMat = eye(6)                             % 6x6 diagonal mat from active components checkboxes
        
        % Line Handles 
        WrenchLH = []                                   % array of Line handles
        EMGLH = []                                      % array of Line handles 
        
        % Axes Handles
        WrenchAxes = []                                 % array of uiAxis handles 
        EMGAxes = []                                    % array of uiAxis handles 
        
        % Buffers
        RawDataFIFOBuffer = []                          % Raw voltage data from DAQ 
        WrenchFIFOBuffer = []                           % Wrench Data FIFO buffer (ch+1 x n) (first row is timestamp)
        FIFOTime = 10 %(sec)                            % Time for FIFO (FIFOSize = FIFOTime * sample rate)
        
        % Configuration Information 
        Date = []                                       % saved date
        datadir = cd                                    % 
        subjID = []                                     % 
        Protocol = []                                   % 
        Notes = []                                      %
        
        WrenchChannels = 0:5                            % daq channels for load cell inputs 
        EMGChannelsAvail = [6 7 16 17 18 19 20 21 22 23]   % daq channels available for EMG inputs (up to 10)
        EMGChannels = []                                % daq channels for EMG inputs
        WrenchChannelNames = {'Fx','Fy','Fz','Mx','My','Mz'} % I want to change this to moments first.. check! 
        EMGChannelNames = {'EMG1','EMG2','EMG3','EMG4','EMG5','EMG6','EMG7','EMG8','EMG9','EMG10'}
        
        % filepaths
        CalMatPath = jr3_loadcell.cal_mat               % Calibration Decoupling Matrix from JR3 (6x6) converts Voltage to lbs and in-lb
        
        % settings
        collectEMG = 0                                  % boolean: 0 = don't collect EMG; 1 = collect EMG
        
        itrial = 1                                      % 
        filename = 'data_trial'                         % 
        
        logdata = 0                                     % boolean: 0: don't save data; 1: save data to file
        starttime = []                                  % saves first time stamp during data logging for end condition
        
        Uw                                              % Wrench Directions to Test - loaded 
        dir_names                                       % Wrench direction names (cell array) loaded

        WrenchDataIdx                                   % Indices of Wrench data from DataAvailable Callback: [F, M]
        EMGDataIdx                                      % Indices of EMG data from DataAVailable Callback 
        
        gauges                                          % object handles for gauges
        gaugenums                                       % object handles for gauge nums
        
        command_flag = 0                                % flag for indicating command text on figure window
        command_delay = 0.5 %s                          % delay in "GO" command after start of acquisition 
        
        XFigWindow = 5 %sec                             % time window for x-axis on plots 
    
    end
    
    
    methods (Access = private)        
        
        %% UNIT CONVERSION %%

        function x_meters = in2m(~,x_inches)
            x_meters = 0.0254 * x_inches;        
        end
        function x_newtons = lb2N(~,x_lbs)
            x_newtons = 4.448*x_lbs;
        end
        function x_Nm = lbin2Nm(~,x_lbin)
            x_Nm = 0.0254*4.448*x_lbin;
        end
        
        %% STATUS TEXT UTILITY FUNCTION %%
        
        function appendStatusText(app, newtext)
            % Add new line to top of status text box
            % INPUTS: newtext - char string to append
            % 
            app.StatusText.Value = [datestr(now,'HH:MM:SS') ': ' newtext; app.StatusText.Value]; 
            drawnow
        end 
        
        %% VISUALIZIATION CODE %%
        
        function axlim2vislim(app)
            % use this to set VisLim property for proper scaling for Target Window
            % FigH must exist already (ie TargetWindow class has been created)
            
            if ~isempty(app.FigH)
                
                % TargetWindow axis limits
                axlim = app.FigH.getAxLimits(); 
                % dimensions of pyramid in (x,y,z) at 0 
                pydims = app.FigH.getPyramidDims();                             
                

                % size of max dimension of pyramid
                maxpy = max(max(pydims(2:3)));
                %set app property
                app.VisLim =   [    axlim(1,1) + maxpy,         axlim(1,2) - maxpy  ;    % fx
                                    axlim(2,1) + maxpy,         axlim(2,2) - maxpy  ;    % fy
                                    axlim(3,1) + maxpy,         axlim(3,2) - maxpy  ;    % fz
                                    -app.py_rot_range,          app.py_rot_range    ;    % mx
                                    -app.py_rot_range,          app.py_rot_range    ;    % my
                                    -app.py_rot_range,          app.py_rot_range    ];   % mz
                                
            else
                warning('VisLim property not set because app.FigH is empty')                
            end          
            
        end
        
        function updFBPyramid(app, wvec, matchbool)
            
            if ~isempty(app.FigH)              
                
                % Compute transform matrix 
                [Tmat, wmag] = app.wrench2Tmat(wvec');
                
                % Update FBPyramid object in TargetWindow 
                app.FigH.updateFBPyramid(Tmat);
                app.FigH.updateGaugeValue(wmag)
                
                % Update gauge color based on if direction matches target               
                switch matchbool
                    case 0 % does not match
                        c = 'y';
                    case 1 % matches
                        c = 'g';
                end
                app.FigH.changeGaugeFillColor(c);               
                
            end
                        
        end
        
        function updateTarget(app)
            
            % only execute if figure window is open and show target checkbox is checked
            if ~isempty(app.FigH)

                % make transform matrix                 
                Tmat = app.wrench2Tmat(app.TargetValues);
                
                app.FigH.updateTargetPyramid(Tmat);                
                              
            end            
        end


        %% GAUGE VISUALIZATION %%
        
        function updateGaugeLims(app)
        % Update the Gauge Indicator limits [min max] in the Gauges Tab
            
            % Compute maximum magnitude of Force and Moment Components
            maxf = vecmath.maxnorm(app.WRanges(1:3,:));
            maxm = vecmath.maxnorm(app.WRanges(4:6,:));
           
            % Set Gauge Object limits 
            app.WMagGauge.Limits = [0 1];
            app.FMagGauge.Limits = [0 maxf];
            app.MMagGauge.Limits = [0 maxm];            
            
        end
        
        function changeGaugeColorLims(app) 
        % Change shaded area indicating target for gauges in Gauges tab based on Target Values
            
            % Break Target Values into unit direction components 
            [fu, ~, mu, ~, ~, ~, ~] = app.wrench2components(app.FBCompsMat*app.TargetValues);
            
            % Target indicator color 
            color_red = [1 0 0];
            
            % calculate target limits within range [-1 1]
            del = 0.1; % range size
            mlims = vecmath.clip(mu + [-del/2 del/2], -1, 1);
            flims = vecmath.clip(fu + [-del/2 del/2], -1, 1);
            
            % Set Gauge Object Scale Color Properties
            for ii = 1 : 3
                % forces
                app.gauges(ii).ScaleColors = color_red;
                app.gauges(ii).ScaleColorLimits = mlims(ii,:);
                % moments
                app.gauges(ii+3).ScaleColors = color_red;
                app.gauges(ii+3).ScaleColorLimits = flims(ii,:);
            end          
            
        end
    
        function updateGauges(app, wrench)
        % Update all gauge indicators in Gauges tab based on current measured wrench 
            
            % Break wrench into components
            [fu, fmag, mu, mmag, wmagn, ~, ~] = app.wrench2components(wrench);
            
            % update individual forces and moments
            for ii = 1:3
                % forces
                app.gaugenums(ii).Value = fu(ii);
                app.gauges(ii).Value = fu(ii);
                % moments
                app.gauges(ii+3).Value = mu(ii); 
                app.gaugenums(ii+3).Value = mu(ii);
            end             
            
            % update combo indicators
            mags = [mmag fmag wmagn];
            for ii = 1 : 3
                app.gauges(ii+6).Value = mags(ii);
                app.gaugenums(ii+6).Value = mags(ii);
            end
            
            
        end
        
        function setGaugeLims(app)
            if ~isempty(app.FigH)
                % change gauge limits
                %maxw = vecmath.maxnorm(app.WRanges);           
                app.FigH.changeGaugeLimits([0 1]);
                
            end           
        end   
    
        %% TRANSFORMATIONS %%
        
        function [fu, fmag, mu, mmag, wmagn, fmagn, mmagn] = wrench2components(app, wrench)            
           
            % unit vectors and magnitudes
            [fu, fmag] = vecmath.unitvec(wrench(1:3));
            [mu, mmag] = vecmath.unitvec(wrench(4:6));
            
            % Max magnitude possible based on WRange
            fmaxmag = vecmath.maxnorm(app.WRanges(1:3,:));
            mmaxmag = vecmath.maxnorm(app.WRanges(4:6,:));
            
            % normalized magnitudes
            fmagn = fmag/fmaxmag;
            mmagn = mmag/mmaxmag;
            wmagn = (fmagn + mmagn)/2;           
            
        end 
    
        
        function [Tmat, wmagn] = wrench2Tmat(app, wrench)
        % 
        % Inputs: wrench: (6x1) (fx,fy,fz,mx,my,mz)' 
        % Outputs:  Tmat: (4x4) Transform matrix in vis coordinates
        %           wmagn: (scalar) normalized wrench magnitude [0 1]
        
            % Only consider active components of vector
            wrench = app.FBCompsMat*wrench;           
            % Break down wrench into components
            [fu, fmag, mu, mmag, wmagn, fmagn, mmagn] = app.wrench2components(wrench); 
            
            % Scale magnitudes based on target view mode
            switch app.TargetMode
                
                case {1,'1'} % match                
                    % pass through wrench components from above  
                    
                case {2,'2'} % dir and mag
                   
                    if mmag ~= 0
                       
                       % ratio of force:moment 
                       fmr = fmag/mmag;                       
                       
                       if fmagn > mmagn % limited by force                            
                            fmag = app.WRanges(1,2)*(2/3); % change to a fmax property
                            mmag = fmag/fmr;
                        else % mmagn >= fmagn % limited by moment (includes fmag == 0) 
                            mmag = app.WRanges(4,2)*(2/3); % change to an mmax property
                            fmag = mmag*fmr;
                       end
                       
                    else % mmag == 0 
                       fmag = app.WRanges(1,2)*(2/3); % change to a fmax property
                    end 

                otherwise
                    error('invalid TargetModeDropDown.Value')
            end    
            
            % Transform to VisLim Coords
            fvec_vis = vecmath.changeScale(fmag*fu, app.WRanges(1:3,:), app.VisLim(1:3,:));
            mvec_vis = vecmath.changeScale(mmag*mu, app.WRanges(4:6,:), app.VisLim(4:6,:));
            [mu_vis, mmag_vis] = vecmath.unitvec(mvec_vis);
            
            % Build the transformation matrix        
            R = vecmath.RotateAxis(mu_vis, mmag_vis);
            gR = vecmath.TransformMat(R, fvec_vis);            
            % Scale size of pyramid based on fx component   
            xs = vecmath.changeScale(fvec_vis(1), app.VisLim(1,:), app.py_scale_range);
            gx = vecmath.scaleMat(xs);            
            % Combine into Transform Matrix Tmat
            Tmat = gR*gx;
            
        end
    
        % note: could change this so compute target parts only once. then
        % wouldn't need to compute every time this is called. Also, could
        % save target error params to know what was used to decide whether
        % target matched or not. add to input: rtar, fut, mut ? or at least
        % tvec
    
        function bool = directionMatch(app, wvec)
        % input:  wvec (6x1) wrench vector 
        % output: bool :    0 - does not match target direction
        %                   1 - matches target direction
        %         wmag :    wrench magnitude of active components
        
            
            % only consider active components
            wvec = app.FBCompsMat*wvec';
            tvec = app.FBCompsMat*app.TargetValues;
            
            % Compute target and wrench directions and normalized magnitudes for f and m                 
            [fut, ~, mut, ~, ~, fmagt, mmagt] = app.wrench2components(tvec);
            [fuc, ~, muc, ~, ~, fmagc, mmagc] = app.wrench2components(wvec);
            rtar = mmagt/(mmagt+fmagt); % target ratio
            rcur = mmagc/(mmagc+fmagc); % measured ratio
            rerr = abs(rtar-rcur); % difference in ratio
            
            % compute angle between moment and force vecs
            thm = abs(vecmath.aBetween2Vecs(muc,mut));
            thf = abs(vecmath.aBetween2Vecs(fuc,fut));
            
            % allowable direction error
            ratio_err = app.RatioErrorSpinner.Value;
            derr = app.DirMatchErrorEditField.Value;       
            
          
            if rtar == 0 % pure force (mmagt = 0)
                % ignore thm, instead keep mmagc close to zero
                if thf < deg2rad(derr) && mmagc < ratio_err 
                    bool = 1;
                else
                    bool = 0;
                end
            elseif rtar == 1 % pure moment (fmagt = 0)
                % ignore fuc and fut, instead keep fmagc close to zero
                if thm < deg2rad(derr) && fmagc < ratio_err
                    bool = 1;
                else
                    bool = 0;
                end
            elseif rtar > 0 && rtar < 1 % combo force and moment 
                % derr may be too sensitive if moment or force has
                % very small contribution (can tell by rtar
                % ratio..), in that case, should we increase derr
                % for that component?? if rtar is close to 0, then
                % the moment component is very small and thm is
                % sensitive. If rtar is close to 1, the force
                % component is very small and thf is sensitive. 
                if max(thm,thf) < deg2rad(derr) && rerr < ratio_err
                    bool = 1;
                else
                    bool = 0;
                end
            else 
                %warning('All components of target wrench were zero')
                bool = 0;
            end
            
            % print to gui - mostly for debug - remove later if you want
            if ~isnan(thm) % avoid error if nan
                app.MerrEditField.Value = rad2deg(thm);
            else
                app.MerrEditField.Value = -1;
            end
            if ~isnan(thf) % avoid error if nan
                app.FerrEditField.Value = rad2deg(thf);
            else
                app.FerrEditField.Value = -1;
            end
            %app.RerrEditField.Value = rerr; % giving error
                                
        end


        % left hand coordinate frame :(
        % Compute Coordinate Transform for Load cell Frame -> World Frame -> Head Frame
        function gT = computeCoordinateTransform(app, p, th)
            %   p: translation vector (x,y,z) from world frame to head frame (in meters) 
            %   th: vector of angles about each axis (thX, thY, thZ) (in radians) to rotate in order z-y-x
            %   gT: adjoint matrix to transform wrench from load cell to head frame coordinates
            %       wb = gT * wa whre w is in form [F M]' NOT [M F]
            
            % Units for p are meters, th is radians
            
            % Load Cell Coordinate Frame: located at center of load cell 
            %       x: away from label
            %       y: toward cable connection perp to x
            %       z: forward (out from sensor side)
            % (R-Hand coordinate frame from robot side, L-Hand from sensor side)
            
            % World Coordinate Frame: located at center of load cell 
            %       x:  forward (horizontal to ground)
            %       y:  vertical up (parallel to gravity) 
            %       z:  to subject's right (horizontal to ground)
            % 
            
            % Head Coordinate Frame: located at C2 dens
            %       x:  forward
            %       y:  vertical up
            %       z:  to subject's right          
            % 
            
            
            % Transform load cell to world frame

            % Reflection about xy plane  
            Rr = vecmath.ReflectAboutPlane('xy');
            % rotate 90 deg about y axis 
            Ry = vecmath.RotateAxis([0 1 0]',deg2rad(-90));
            % no translation
            p0 = zeros(1,3);

            A_LC2W = vecmath.adjointMatrix(Ry*Rr,p0);
                       
            
            switch app.FBFrameMode
                case {1, '1', 'Hframe'} % head frame
                    % create rotation matrices about each axis and translation vector             
                    Rx = vecmath.RotateAxis([1 0 0]', th(1));
                    Ry = vecmath.RotateAxis([0 1 0]', th(2));
                    Rz = vecmath.RotateAxis([0 0 1]', th(3));                    
                    % adjoint matrix from world frame to head frame
                    A_W2H = vecmath.adjointMatrix(Rx*Ry*Rz, p);                    
                    % adjoint matrix from load cell to head frame
                    gT = A_LC2W * A_W2H;
                case {2, '2', 'Wframe'} % world frame
                    gT = A_LC2W;
                case {3, '3', 'LCframe'} % load cell frame
                    gT = eye(6);
                otherwise % not transformed
                    gT = eye(6);
            end                   
            
        end
    
        
        %% UTILITY FUNCTIONS %%
        
        function new_value = deleteHandle(~,handle)
            % delete handle and set value to empty
            if ~isempty(handle)
                for ii = 1 : length(handle) % single or list of handles
                    if ishandle(handle(ii))
                        delete(handle(ii))
                    end                    
                end
                new_value = []; % set handle to empty
            else % if already empty
                new_value = handle; % pass through
            end
            
        end
    
        
        function new_value = closeFigure(~,handle)
            % close figure window and delete handle
            if ~isempty(handle)
                if ishandle(handle)
                    close(handle) % executes figure's CloseRequestFcn
                end
                new_value = [];
            else
                new_value = handle;
            end         
            
        end 
    
        function lh = clearLine(~, lh)
            % This clears the data in the line handle but does not delete the handle 
            for ii = 1 : length(lh)
                if ishandle(lh(ii))
                    set(lh(ii), 'XData', [],'YData', [])
                end
            end
        end  
        
        function closeApp_Callback(app, ~, event, isAcquiring)
            % closeApp_Callback Executes after "Close Confirm" dialog window
            %  "Close Confirm" dialog window is launched from DataAcquisitionLiveCloseRequest.
            %   before closing app if acquisition is currently on (isAcquiring=true) clean-up 
            %   DAQ session and close file if logging.
            
            switch event.SelectedOption
                case 'OK'
                    if isAcquiring
                        % Acquisition is currently on
                        stop(app.DAQSession);
                        app.deleteHandle(app.DataAvailableLH);
                        app.deleteHandle(app.DAQSession);
                    else
                        % Acquisition is stopped
                    end
                    
                    % Re-enable warning when session rate is adjusted to closest supported sampling rate
                    warning('on', 'daq:Session:closestRateChosen')
                    delete(app);
                case 'Cancel'
                    % Continue
            end
            
        end   
    
  
        %% DATA IO %%
    
        function data = storeDataInFIFO(~, data, buffersize, datablock)
            % storeDataInFIFO Store continuous acquisition data in a FIFO data buffer
            %  Storing data in a finite-size FIFO buffer is used to plot the latest "N" seconds of acquired data for
            %  a smooth live plot update and without continuously increasing memory use.
            %  The most recently acquired data (DATABLOCK) is added to the buffer and if the amount of data in the 
            %  buffer exceeds the specified buffer size (BUFFERSIZE) the oldest data is discarded to cap the size of
            %  the data in the buffer to BUFFERSIZE.
            %  input DATA is the existing data buffer (array nchan x N).
            %  BUFFERSIZE is the desired buffer size (maximum number of columns in DATA buffer) and can be changed.
            %  DATABLOCK is a new data block to be added to the buffer (array nchan x K).
            %  output DATA is the updated data buffer (array nchan x M).
            
            % If the data size is greater than the buffer size, keep only the
            % the latest "buffer size" worth of data
            % This can occur if the buffer size is changed to a lower value during acquisition
                
            % length of data and data block (number of samples)
            ndata = size(data,2);
            ndatablock = size(datablock,2);
            
            if ndata > buffersize
                data = data(:,end-buffersize+1:end);
            end
            
            if ndatablock < buffersize
                % Data block size (number of columns) is smaller than the buffer size
                if ndata == buffersize
                    % Current data size is already equal to buffer size.
                    % Discard older data and append new data block,
                    % and keep data size equal to buffer size.
                    shiftPosition = ndatablock;
                    data = circshift(data,-shiftPosition,2); % shift along 2nd dim
                    data(:,end-shiftPosition+1:end) = datablock;
                elseif (ndata < buffersize) && (ndata + ndatablock > buffersize)
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size greater than the buffer size.
                    data = [data datablock];
                    shiftPosition = ndata - buffersize;
                    data = circshift(data,-shiftPosition,2); % shift along 2nd dim
                    data(:,buffersize+1:end) = [];
                else
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size smaller than or equal to the buffer size.
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(:,end-buffersize+1:end);
            end            
        end
        
        function wrenchdata = raw2wrench(app, rawWrench)
            % rawWrench: nsamples x 6 (fx,fy,fz,mx,my,mz) voltage from load cell 
            % wrenchdata: nsamples x 6 transformed into N and Nm in head frame
            
            % (ch 1:3 are force, ch 4:6 are moment)            
            % then transform by Calibration Matrix 
            % (CalMatrix and raw data are in lbs-in units)
            wrenchdata = app.CalMatrix * (rawWrench' - app.LCZeroOffset); % (#ch x nsamples)
            % transform  from load cell frame to head frame
            % ( LC2Head is in N and m; convert data from lbs and lbs-in to N and Nm)
            wrenchdata = app.LC2Head' * [app.lb2N(wrenchdata(1:3,:)); app.lbin2Nm(wrenchdata(4:6,:))];
            % transpose back to nsamples x 6
            wrenchdata = wrenchdata';
        end

        function DataAvailableCallback(app, ~, event)
            % dataAvailable_Callback Executes on DAQ session DataAvailable event
            %  This callback function gets executed periodically as data is acquired.
            %  event.TimeStamps (nx1) and event.Data (n x nch)         
            
            % organize data and store in FIFO buffers (nch+1 x #samples)
            app.RawDataFIFOBuffer = app.storeDataInFIFO(app.RawDataFIFOBuffer, app.FIFOTime*app.sRate, [event.TimeStamps event.Data]');
            
            % TIMESTAMPS
            timestamps = event.TimeStamps; % make (#scans x 1)   
            ctime = timestamps(ceil(length(timestamps)/2)); % single time point (midpoint)
            
            % WRENCH
            % (ch 1:3 are force, ch 4:6 are moment)
            wrenchdata = app.raw2wrench(event.Data(:,app.WrenchDataIdx)); % nsamples x 6 ch
            % Don't need such a high sampling rate for the wrench. Instead
            % take the mean value for updating visuals 
            meanwrench = mean(wrenchdata,1); % 1 x 6 ch
            wmag = norm(meanwrench);
            % see if wrench matches target
            matchbool = app.directionMatch(meanwrench);
            
            % Update the Wrench Buffer ( in N and m units!! ) 
            app.WrenchFIFOBuffer = app.storeDataInFIFO(app.WrenchFIFOBuffer, app.FIFOTime*app.WsRate, [ctime meanwrench wmag matchbool]');        
            
           
            % update target window if open too 
            if ~isempty(app.FigH)
                
                % update FBPyramid with new data
                app.updFBPyramid(meanwrench,matchbool);
                
                if app.TabGroup.SelectedTab == app.GaugesTab
                    app.updateGauges(meanwrench);
                end            
                
            end
            
            % Update plot data if in current view
            if app.TabGroup.SelectedTab == app.WrenchPlotsTab
                app.updatePlots(app.WrenchAxes, app.WrenchLH, app.WrenchFIFOBuffer([1 app.WrenchDataIdx+1],:)');
            end
            
            nEMG = length(app.EMGChannels); 
            if all([app.TabGroup.SelectedTab == app.EMGPlotsTab, nEMG > 0])
                % col 1 is timestamps, then rest of EMG channels are offset
                % by 1 due to timestamps
                emgdata = app.RawDataFIFOBuffer([1 app.EMGDataIdx+1],:)';
                app.updatePlots(app.EMGAxes, app.EMGLH, emgdata);
            end 
            
            
            % If acquiring data
            if app.logdata           
                
                if isempty(app.starttime)
                    app.starttime = timestamps(1);
                    
                    % make fifos store data for length of sample time during acquisition 
                    app.FIFOTime = app.sTime; 
                    
                    % make sure we start off with "Relax" and correct flag
                    app.FigH.updateCommand('Relax','k');
                    app.command_flag = 0;
                end
                
                % indicate for participant to start trying to match target
                % after delay of app.command_delay 
                if and(timestamps(end) >= app.starttime + app.command_delay,app.command_flag == 0)
                    app.FigH.updateCommand('GO GO GO', 'g');
                    app.command_flag = 1;
                    % insert play sound command here too if I want
                end
                
                % check ending condition
                if timestamps(end) >= app.starttime + app.sTime
                    % stop logging data
                    app.logdata = 0;
                    app.FigH.updateCommand('Relax','k');
                    app.command_flag = 0;
                    
                    % save to .mat file 
                    app.saveData(app.RawDataFIFOBuffer,app.WrenchFIFOBuffer);
                    
                    % record status
                    status_txt = sprintf('Collected trial #%i in direction %i', app.itrial, app.DirIdxSpinner.Value);
                    app.appendStatusText(status_txt);
                    
                    % enable buttons now that finished acquiring
                    app.EnableDisableAcquisition(1)
                    
                    % plot magnitude when matching 
                    [~,wmAx] = app.createNewPlotWindow(1,'Wrench Magnitude',{'Wrench Magnitude'});
                    % make any wrench that did not match direction 0
                    xydata = [app.WrenchFIFOBuffer(1,:); app.WrenchFIFOBuffer(8,:).*app.WrenchFIFOBuffer(9,:)]';
                    app.plotData2Ax(wmAx,xydata)
                    
                    % plot 6D wrench
                    [~,wAx] = app.createNewPlotWindow(6,'Wrench Data',app.WrenchChannelNames);
                    app.plotData2Ax(wAx,app.WrenchFIFOBuffer(1:7,:)');
                    
                    % plot emg to new window
                    if nEMG > 0
                        emgdata = app.RawDataFIFOBuffer([1 app.EMGDataIdx+1],:)';
                        [~,eAx] = app.createNewPlotWindow(nEMG,'EMG Data',app.EMGChannelNames);
                        app.plotData2Ax(eAx,emgdata);
                    end
                    
                    % reset 
                    app.starttime = [];
                    app.FIFOTime = app.XFigWindow; % change buffer back to x-axis limits for figures
                    
                    % update trial number for next trial 
                    app.itrial = app.itrial + 1;
                    app.TrialNumberField.Value = app.itrial;      
                          
                end             
                
            end


        end        

    
        %% FILE MANAGEMENT %%
        
        function saveData(app,rawdata,wrenchdata)
            
            % get dimensions of data
            ncols = size(rawdata,2);            
            
            % Gather metadata in preparation for saving to MAT file
            metadata = app.setupStruct();
            metadata.trialnum = app.itrial; 
            
             % add trial number to filename
            [~, fn, ~] = fileparts(app.filename);
            new_fn = sprintf('%s_%03.0f.mat',fn,app.itrial);
            %new_fn = [fn '_' num2str(app.itrial) '.mat'];
            matFilepath = fullfile(app.datadir, new_fn);
            % if file exists, add _N to end until unique
            matFilepath = app.uniqueFileName(matFilepath, '.mat');         
            
            % Create matfile object to save data
            matObj = matfile(matFilepath);
            matObj.Properties.Writable = true;
            
            % Save data  to mat file    
            matObj.metadata = metadata;
            matObj.wrenchdata = wrenchdata;
            matObj.rawdata = rawdata;
            if ncols > 7
                %matObj.emgdata = rawdata(:,[1 8:end]);
            end
       
            % take just the filename for status update
            [~, fn, ext] = fileparts(matFilepath); 
            status_txt = sprintf('Saving data to ''%s'' file has completed.', [fn ext]);
            app.StatusLabel.Text = status_txt;
            app.appendStatusText(status_txt);
            
        end


        %% PLOTTING %% 
        
        function [lh,ax] = initializePlots(app, gridObj, nCh, ch_names)
            % gridObj = app.WrenchPlotGrid or app.EMGPlotGrid; % Grid
            % nCh = length(app.WrenchChannels) or length(app.EMGChannels);
            % lh : (1 x #ch) array of line handles 
            
            opt.names = ch_names;            
            ax = app.constructAxes(gridObj,nCh,opt);
            
            lh = [];                      
                
            % Create a line plot and store its handle
            for ii = 1 : nCh
                title(ax(ii),ch_names(ii))
                lh(ii) = plot(ax(ii), NaN, NaN);                
            end           
        end 
        
        function Ax = constructAxes(~, gridObj, nAx, opt)
            % 
            % INPUTS:       gridObj
            %               nAx (scalar) number of axes to construct
            %               opt (struct) optional with fields: 
            %                   .names: (char) or vector of chars plot title base
            %                   .ncols: (scalar)
            %                   .xlabel: (char)
            %                   .ylabel: (char)
            % OUTPUTS:      Ax (1 x nAx) vector of uiaxes handles
            % 
            
            % delete any existing axes in grid object
            if ~isempty(gridObj.Children)
                delete(gridObj.Children);
            end
            
            % Compute Grid Size: 
            % # of columns is based on # of axes (reasonable guess)
            % choose ncols based on interval nAx falls in
            ncol_values = [1 2 3 4];
            intervals = [0 1 8 16 Inf];
            ncols = discretize(nAx, intervals, ncol_values, 'IncludedEdge','right');
            
            % can update this to add more things from template axes
            switch nargin
                case 4
                    if isfield(opt,'names')
                        title_base = opt.names;
                    else
                        title_base = '';
                    end
                    if isfield(opt,'ncols')
                        ncols = opt.ncols; % overwrite ncols computed before
                    end
                case 3
                    title_base = '';
%                     xlabel_txt = 't';
%                     ylabel_txt = '';
                otherwise
                    error('incorrect number of inputs')
            end

            % make at least enough rows (ceil rounds up)    
            nrows = ceil(nAx/ncols);
            
            % size gridObj appropriately
            gridObj.ColumnWidth = repmat({'1x'},1,ncols);
            gridObj.RowHeight = repmat({'1x'},1,nrows);
            
            % Create a uiaxes object and place in grid going left->right,
            % up->down. Title sequentially. 
            Ax = [];
            nn = 1;
            for rr = 1 : nrows
                for cc = 1 : ncols

                    cAx = uiaxes(gridObj);
                    cAx.Layout.Row = rr;
                    cAx.Layout.Column = cc;
                    if isscalar(title_base)
                        title_str = sprintf('%s_{%d}',title_base,nn);
                    else
                        title_str = title_base{nn};
                    end
                    cAx.Title.String = title_str;

                    % add new uiaxes object to list
                    Ax = [Ax cAx];                    
                    
                    if nn >= nAx
                        break
                    end
                    nn = nn + 1;                
                end
            end            
            %drawnow
        end
        
        function [f, ax] = createNewPlotWindow(~, nch, fname, chnames)
            
            f = figure('Name', fname);            
            
            % dimensions
            if nch > 1
                ncols = 2;
            else
                ncols = 1;
            end
            nrows = ceil(nch/ncols);            
            
            % create subplots and save axes
            for ii = 1 : nch                
                ax(ii) = subplot(nrows,ncols,ii);
                hold on                
                
                if nargin > 3
                    title(ax(ii), chnames(ii))
                else
                    title(ax(ii), num2str(ii))
                end
            end
            
        end
    
        function plotData2Ax(~, ax, data)
            % data (npts x nch+1) 
            % first col is timestamps
            
            % plot data to ax 
            for ii = 1 : length(ax)              
                plot(ax(ii), data(:,1), data(:,ii+1));                
            end
            
        end
        
        function updatePlots(~, ax, lh, data)
            % ax = app.WrenchAxes or app.EMGAxes; %(1 x #Axes) array of UIAxis handles
            % data(1,:) = app.TimestampsFIFOBuffer; % (1 x #scans) array
            % data(2:end,:) = app.WrenchFIFOBuffer or app.EMGFIFOBuffer; % (#ch x #scans) array

            % set axis data objects

            for ii = 1 : length(lh)
                % assign new data values to line handles 
                if ishandle(lh(ii))
                    set(lh(ii), 'XData', data(:,1), 'YData', data(:,ii+1));
                end
                % set x-axis limits to range of timestamps
                if ishandle(ax(ii))
                    xlim(ax(ii), [data(1,1), data(end,1)]); 
                end                        
            end
  
        end  
    
        function fdata = MovAvgFilter(~, data, fwin)
            % Zero-Phase-Shift (2-sided) Moving Average filter: 
            %       Implemented with matlab filter(b,a,x) function where b = [1/fwin 1/fwin ...] of length(b) = fwin
            %       Based off of filter22() from Eric Perrault 1991                        
            % 
            % Inputs:   data (npts x nchannels) 
            %           fwin (scalar) filter window length
            % 
            % Outputs:  fdata (npts x nchannels) filtered data
        
            % dimensions 
            [npts, nch] = size(data);
        
            % filter numerator coefficients
            fil = ones(fwin,1)/fwin;
            % half length of filter coeff will be thrown out to avoid phase shift
            hl = ceil(length(fil)/2);
        
            % add padding to data before filtering for later phase shift correction
            padding = zeros(length(1:hl),nch);
            data = [data ; padding];
            
            % filter along first dimension
            fdata = filter(fil, 1, data, [], 1);
        
            % throw out first bit to avoid phase shift
            fdata = fdata(hl : npts + hl-1, :);
            
        end      


        %% GUI MANAGEMENT %%
        
        function EnableDisableTargetWindowOpen(app, bool)
            
           switch bool 
               
               case 0 % close window
                   
                   % Change button text 
                    app.OpenTargetWindowButton.Text = 'Open Target Window';                  
                    
                    % re-enable changing mode 
                    app.TargetModeDropDown.Enable = 'on';
               
               case 1 % open window
                   
                   % Change button text 
                    app.OpenTargetWindowButton.Text = 'Close Target Window';   
                    
                    % disable changing mode 
                    app.TargetModeDropDown.Enable = 'off';
           
           end
           
        end
        
        function EnableDisableInitialization(app, bool)
           
            switch bool 
               
                case 0 % DAQ initialized and Background Collection Running                  
                    app.InitializeDAQButton.Enable = 'off';
                    app.scanRateField.Enable = 'off';
                    app.CollectEMGCheckBox.Enable = 'off';
                    app.nEMGSpinner.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    app.ZeroLoadCellButton.Enable = 'on';
                    app.DAQDeviceDropDown.Enable = 'off';
                    app.AcquireDataButton.Enable = 'on';
                    app.LoadConfigButton.Enable = 'off';
                    
               
               case 1 % DAQ stopped and Cleared; not running 
                    app.InitializeDAQButton.Enable = 'on';
                    app.StopButton.Enable = 'off';
                    app.scanRateField.Enable = 'on';
                    app.CollectEMGCheckBox.Enable = 'on';
                    app.nEMGSpinner.Enable = 'on';
                    app.ZeroLoadCellButton.Enable = 'off';
                    app.AcquireDataButton.Enable = 'off';
                    app.DAQDeviceDropDown.Enable = 'on';
                    app.LoadConfigButton.Enable = 'on';
                    
           end
        end
    
        function EnableDisableAcquisition(app, bool)
            
            switch bool
                
                case 0 % currently acquiring 
                    app.StatusLabel.Text = 'Status: Acquiring Data';
                    app.AcquireDataButton.Enable = 'off';
                    app.ZeroLoadCellButton.Enable = 'off';
                    app.sampleTimeField.Enable = 'off';
                    
                case 1 % not acquiring data
                    app.AcquireDataButton.Enable = 'on';
                    app.ZeroLoadCellButton.Enable = 'on';
                    app.sampleTimeField.Enable = 'on';
            end
        end      
    
        
        %% CONFIGURATION SAVING AND LOADING %% 
        
        function setup = setupStruct(app)
            % app property values to save into setup structure 

            setup.subject = struct(  'Date',                 date,                    ...
                                     'subjID',               app.subjID,              ...
                                     'Protocol',             app.Protocol,            ...
                                     'Notes',                app.Notes);
            setup.daq = struct(      'WrenchChannels',       app.WrenchChannels,      ...
                                     'EMGChannels',          app.EMGChannels,         ...
                                     'WrenchChannelNames',  {app.WrenchChannelNames}, ...
                                     'EMGChannelNames',     {app.EMGChannelNames},    ...
                                     'sRate',                app.sRate,               ...
                                     'sTime',                app.sTime);
            setup.target = struct(   'TargetValues',         app.TargetValues,        ...
                                     'dir_index',            app.DirIdxSpinner.Value, ...
                                     'dir_name',             app.DirNameField.Value,  ...
                                     'VisLim',               app.VisLim,              ...
                                     'WRanges',              app.WRanges,             ...
                                     'DirErrorWindow',       app.DirMatchErrorEditField.Value, ...
                                     'RatioErrorWindow',     app.RatioErrorSpinner.Value, ...
                                     'FBCompsMatrix',        app.FBCompsMat);   
            setup.params = struct(   'LC2Head',              app.LC2Head,             ...
                                     'CalMatrix',            app.CalMatrix,           ...
                                     'ZeroOffsetV',          app.LCZeroOffset);            
            
        end
    
        function value = ifisfield(~, s, fstr, cval)
            % only assign value from struct if fstr is a field of the struct s
            % otherwise keep current value cval
            if isfield(s,fstr)
                value = s.(fstr);
            else
                value = cval;
            end
        end
    
        function loadConfigUpdate(app, setup)
            
            % LOAD VALUES FROM SETUP STRUCT
            % setup.subject
            s = setup.subject;
            app.subjID = app.ifisfield(s,'subjID',app.subjID);
            app.Protocol = app.ifisfield(s,'Protocol',app.Protocol);
            app.Notes = app.ifisfield(s,'Notes',app.Notes);
            % setup.daq
            s = setup.daq;
            app.WrenchChannels = app.ifisfield(s,'WrenchChannels',app.WrenchChannels);
            app.EMGChannels = app.ifisfield(s,'EMGChannels',app.EMGChannels);
            app.WrenchChannelNames = app.ifisfield(s,'WrenchChannelNames',app.WrenchChannelNames);
            app.EMGChannelNames = app.ifisfield(s,'EMGChannelNames',app.EMGChannelNames);
            app.sRate = app.ifisfield(s,'sRate',app.sRate);
            app.sTime = app.ifisfield(s,'sTime',app.sTime);
            % add nEMG
            % setup.target
            s = setup.target;
            app.TargetValues = app.ifisfield(s,'TargetValues',app.TargetValues);
            app.VisLim = app.ifisfield(s,'VisLim',app.VisLim);
            app.WRanges = app.ifisfield(s,'WRanges',app.WRanges);
            % setup.params
            s = setup.params;
            app.LC2Head = app.ifisfield(s,'LC2Head',app.LC2Head);           
            app.CalMatrix = app.ifisfield(s,'CalMatrix',app.CalMatrix);
            
            % UPDATE UI COMPONENTS            
            % subject
            app.ParticipantIDField.Value = app.subjID;
            app.ProtocolField.Value = app.Protocol;
            app.NotesField.Value = app.Notes;
            % daq
            app.scanRateField.Value = app.sRate;
            app.sampleTimeField.Value = app.sTime;
            % add nEMGspinner
            % target
            app.TargetTable.Data = app.TargetValues;
            app.TargetAxesLimitsTable.Data = [app.VisLim(1:3,:); rad2deg(app.VisLim(4:6,:))];           
            
        end
        
        function fullName = uniqueFileName(~, fullName, ext)
            % fullName includes the path (relative or not)
            % ext is the extension (ie '.mat')
            [pn, fn, ~] = fileparts(fullName);
            
            iter = 0;
            while isfile(fullName) % file exists
                iter = iter + 1;
                new_fn = [fn '_' num2str(iter) ext];
                fullName = fullfile(pn, new_fn);
            end
            
        end
    
        %% INITIALIZATION %%
        
        function cal_mat = loadCalMatrix(~,mat)

            if ismatrix(mat) 
                cal_mat = mat;               
            else 
                % If error, just use identity matrix is being used 
                cal_mat = eye(6);
                warndlg('Could not find calibration matrix. Using identity matrix instead.','Calibration Matrix');
            end

            
        end
        
        function initializeAppVars(app)


            % get calibration matrix 
            app.CalMatrix = app.loadCalMatrix(app.CalMatPath);
            
            % set to default values from ui control
            app.collectEMG = app.CollectEMGCheckBox.Value;
            
            app.sRate = app.scanRateField.Value;
            app.sTime = app.sampleTimeField.Value;
            
            app.subjID = app.ParticipantIDField.Value;
            app.Protocol = app.ProtocolField.Value;
            app.Notes = app.NotesField.Value;      
            
            app.TargetMode = str2num(app.TargetModeDropDown.Value);
            app.FBFrameMode = str2num(app.WrenchFrameDropDown.Value);
                                
                
            % Target Axes 
            app.maxFSpinner.Value = max(max(app.WRanges(1:3,:)));
            app.maxMSpinner.Value = max(max(app.WRanges(4:6,:)));
            app.updateGaugeLims();
            
            % Target Values
            app.TargetTable.Data = app.TargetValues;
            app.changeGaugeColorLims();
            
            % update adjoint matrix to change coordinates from load cell -> head frame
            W2LCp = app.in2m([5 -6 0]); % meters 
            W2LCth = deg2rad([0 0 0]); % radians
            app.HeadFrameTable.Data = [W2LCp W2LCth]';
            app.LC2Head = app.computeCoordinateTransform(W2LCp,W2LCth);         
            
            % create array of gauge objects
            app.gauges =  [     app.MxGauge         app.MyGauge         app.MzGauge ...
                                app.FxGauge         app.FyGauge         app.FzGauge ...
                                app.MMagGauge       app.FMagGauge       app.WMagGauge];
            app.gaugenums = [   app.FxMagEditField  app.FyMagEditField  app.FzMagEditField ...
                                app.MxMagEditField  app.MyMagEditField  app.MzMagEditField ...
                                app.MMagEditField   app.FMagEditField   app.WMagEditField];
                            
            app.FIFOTime = app.XFigWindow; % set FIFO buffer time to desired fig window x-axis limit
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % this is run at start up 
            
            % Detect all connected devices
            devices = daq.getDevices;
            
            % Store DAQ device information into DevicesInfo app property
            app.DevicesInfo = devices;
            
            % populate the device drop down list with cell array of composite device names (ID + model) 
            deviceDescriptions = cellstr(string({devices.ID}') + " [" + string({devices.Model}') + "]");
            app.DAQDeviceDropDown.Items = ['Select a device'; deviceDescriptions];
            
            % Assign dropdown ItemsData to correspond to device index + 1
            % (first item is not a device)
            app.DAQDeviceDropDown.ItemsData = 1:numel(devices)+1;  
            
            % initialize app. properties 
            app.initializeAppVars();                       
            
            % Current data directory
            app.CurrentDirectoryTextArea.Value = app.datadir;
            
        end

        % Button pushed function: InitializeDAQButton
        function InitializeDAQButtonPushed(app, event)
            
            deviceIndex = app.DAQDeviceDropDown.Value - 1;
            
            if deviceIndex > 0
                
                % disable/enable Buttons 
                app.EnableDisableInitialization(0);
                
                % Delete DAQ objects if they already exist to avoid errors            
                app.DAQSession = app.deleteHandle(app.DAQSession);    
                app.DataAvailableLH = app.deleteHandle(app.DataAvailableLH);
                
                % Get the device information needed
                deviceID = app.DevicesInfo(deviceIndex).ID;
                vendor = app.DevicesInfo(deviceIndex).Vendor.ID;
                
                % Create a new DAQ session
                app.DAQSession = daq.createSession(vendor);
                measurementType = 'Voltage';
                addAnalogInputChannel(app.DAQSession, deviceID, [app.WrenchChannels app.EMGChannels], measurementType);
                
                % Set DAQ object sampling rate and time
                app.DAQSession.Rate = app.sRate;
                app.DAQSession.IsContinuous = true;
                % wrench feedback and fifo will update every 100 samples (if sRate
                % is 1k samples/sec and WsRate is 10x/sec)
                app.DAQSession.NotifyWhenDataAvailableExceeds = app.sRate/app.WsRate; 
                
                % Create listener for DataAvailable event and specify callback function
                app.DataAvailableLH = addlistener(app.DAQSession,'DataAvailable', ...
                    @(src,event) DataAvailableCallback(app, src, event));   
                
                % indices of data from channels created here that will be
                % reported in DataAvailableCallback
                app.WrenchDataIdx = 1:length(app.WrenchChannels);
                app.EMGDataIdx = (1:length(app.EMGChannels)) + length(app.WrenchChannels);
                
                % reset FIFO buffer data
                app.WrenchFIFOBuffer = [];
                app.RawDataFIFOBuffer = [];

                
                % start collecting data in background (continuous mode)
                startBackground(app.DAQSession); 
                
                % initialize plots
                [app.WrenchLH, app.WrenchAxes] = app.initializePlots(app.WrenchPlotGrid, length(app.WrenchDataIdx), app.WrenchChannelNames);
                
                if app.collectEMG == 1 && ~isempty(app.EMGChannels)
                    [app.EMGLH,app.EMGAxes] = app.initializePlots(app.EMGPlotGrid, length(app.EMGDataIdx), app.EMGChannelNames);
                end
                
                % log status
                nWrench = length(app.WrenchDataIdx);
                nEMG = length(app.EMGDataIdx);                
                app.appendStatusText(sprintf('Initialized with %i wrench channels and %i EMG channels',nWrench,nEMG));
                
            else
                % If no device is selected
                uialert(app.TargetDAQUI,'Select a valid DAQ Device from the dropdown menu.','No DAQ Device Selected')
            end   
                 
            
        end

        % Button pushed function: AcquireDataButton
        function AcquireDataButtonValueChanged(app, event)
           
            % disable buttons and update status for acquisition
            app.EnableDisableAcquisition(0)
          
            % set logdata flag to 1 to start saving data to file in data available callback function 
            app.logdata = 1;


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            % disable/enable Buttons
            app.EnableDisableInitialization(1);
            
            % stop and clear DAQ session
            if ~isempty(app.DAQSession)
                stop(app.DAQSession);
                app.DAQSession = app.deleteHandle(app.DAQSession);
            end            
            
            % clear line handles to remove feedback 
            if ishandle(app.FigH) % target window is open
               % fix
            end
            
        end

        % Value changed function: OpenTargetWindowButton
        function OpenTargetWindowButtonValueChanged(app, event)
            targetOpen = app.OpenTargetWindowButton.Value;
            
            switch targetOpen
                
                case 0 % Close Figure and Reset Objects                    
                    
                    % update gui
                    app.EnableDisableTargetWindowOpen(0);
                    
                    % Close Figure and delete handle 
                    delete(app.FigH);
                    app.FigH = [];  
                    
                    
                case 1 % Open Figure and Initialize Objects
                    % update gui
                    app.EnableDisableTargetWindowOpen(1);
                    
                    % open figure and axes for wrench feedback 
                    app.FigH = TargetWindow();
                    app.FigH.createFBPyramid();
                    app.FigH.createTargetPyramid();
                    
                    % set up VisLim property for proper scaling 
                    app.axlim2vislim();
                    
                    % set view
                    app.ViewDropDownValueChanged();
                    app.FigH.changeFBPyramidTransparency(app.FBTransparencySpinner.Value);
                    app.FigH.changeTPyramidTransparency(app.TargetTransparencySpinner.Value);                    
                    
                    % set gauge limits
                    app.setGaugeLims();
                    
                    % initialize
                    app.ShowTargetCheckBoxValueChanged();
                    app.ShowFeedbackCheckBoxValueChanged();               
                    
            end
            
        end

        % Cell edit callback: TargetTable
        function TargetSetValueChanged(app, event)
            
            % update target settings property                        
            app.TargetValues = app.TargetTable.Data;             
                       
            % update target
            app.updateTarget();
            app.changeGaugeColorLims() 
            
        end

        % Button pushed function: ZeroLoadCellButton
        function ZeroLoadCellButtonPushed(app, event)
            if ~isempty(app.DAQSession) % daq is initialized
                if app.DAQSession.IsRunning  % daq is acquiring data
                    if ~isempty(app.RawDataFIFOBuffer) % has acquired at least a some data
                        % set app property to current load cell data 
                        cdata = app.RawDataFIFOBuffer(app.WrenchDataIdx+1,:);
                        amt = size(cdata,2);
                        if amt > 50
                            LCData = mean(cdata(:,end-50:end),2);
                        else
                            LCData = mean(cdata,2);
                        end                        
                        app.LCZeroOffset = LCData;
                        
                    end
                end
            end           

        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            
            if ~isempty(app.subjID)
                
                setup = app.setupStruct();
                
                if ~isempty(app.datadir)
                    [fname,pname] = uiputfile('','Save the setup file (*.mat)',[app.datadir '\' app.subjID '_Setup.mat']);
                else 
                    [fname,pname] = uiputfile('','Save the setup file (*.mat)',[app.subjID '_Setup.mat']);
                end
                
                if fname ~= 0
                    save(fullfile(pname,fname),'setup');
                else
                    warndlg('Setup file was not saved','Save Setup')
                end
                
            else
                warndlg('Please fill the participant information form first','Save Setup');
            end           
            
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            
            if ~isempty(app.datadir)
                [fname,pname] = uigetfile([app.datadir '\*.mat'], 'Select the setup file (*.mat)');
            else 
                [fname,pname] = uigetfile([pwd '\*.mat'], 'Select the setup file (*.mat)');
            end
            
            if fname ~= 0 
                load(fullfile(pname,fname), 'setup');
                if exist('setup')
                    app.loadConfigUpdate(setup);                           
                end
            end
        end

        % Value changed function: ParticipantIDField
        function ParticipantIDFieldValueChanged(app, event)
            app.subjID = app.ParticipantIDField.Value;            
        end

        % Value changed function: ProtocolField
        function ProtocolFieldValueChanged(app, event)
            app.Protocol = app.ProtocolField.Value;            
        end

        % Value changed function: NotesField
        function NotesFieldValueChanged(app, event)
            app.Notes = app.NotesField.Value;            
        end

        % Value changed function: scanRateField
        function scanRateFieldValueChanged(app, event)
            app.sRate = app.scanRateField.Value;            
        end

        % Value changed function: sampleTimeField
        function sampleTimeFieldValueChanged(app, event)
            app.sTime = app.sampleTimeField.Value;            
        end

        % Value changed function: CollectEMGCheckBox
        function CollectEMGCheckBoxValueChanged(app, event)
            collectEMGbool = app.CollectEMGCheckBox.Value;
            
            % save state into property
            app.collectEMG = collectEMGbool;
            
            switch collectEMGbool
                
                case 0 % unchecked
                    
                    % stop collecting EMG
                    app.nEMGSpinner.Value = 0;
                    app.nEMGSpinner.Enable = 'off';
                    app.EMGChannels = [];
                    
                case 1 % checked
                    
                    % start collecting EMG signals                    
                    app.nEMGSpinner.Enable = 'on';
                    
            end
            
            % run value change callback
            app.nEMGSpinnerValueChanged()                
            
        end

        % Value changed function: nEMGSpinner
        function nEMGSpinnerValueChanged(app, event)
            
            % number of EMG channels to collect
            nEMG = app.nEMGSpinner.Value;           
            
            % set EMGChannels property to correct channels and initialize plots 
            if (nEMG > 0) && (app.collectEMG == 1)
                app.EMGChannels = app.EMGChannelsAvail(1:nEMG);                  
            else % nEMG == 0
                app.EMGChannels = [];                
            end 
            
            % delete previous line handles 
            %app.EMGLH = app.deleteHandle(app.EMGLH);
            
            % update EMG axes and line handles
            %[app.EMGLH,app.EMGAxes] = app.initializePlots(app.EMGPlotGrid, length(app.EMGChannels), app.EMGChannelNames);
            
        end

        % Close request function: TargetDAQUI
        function TargetDAQUICloseRequest(app, event)

            if ~isempty(app.DAQSession)
                if app.DAQSession.IsRunning
                    acquiring = 1;
                else
                    acquiring = 0;
                end
            else
                acquiring = 0;
            end
            
            if acquiring
                question = 'Abort acquisition and close app?';
                
            else
                % Acquisition is stopped
                question = 'Close app?';
            end
            
            uiconfirm(app.TargetDAQUI, question,'Confirm Close',...
                'CloseFcn',@(src,event) closeApp_Callback(app,src,event,acquiring));                                  
            
        end

        % Value changed function: DAQDeviceDropDown
        function DAQDeviceDropDownValueChanged(app, event)
            
            app.daqDevice = app.DAQDeviceDropDown.Value - 1;                           

        end

        % Value changed function: ShowTargetCheckBox
        function ShowTargetCheckBoxValueChanged(app, event)
            
            % get current value 
            bool = app.ShowTargetCheckBox.Value;
            
            if ~isempty(app.FigH) % target feedback window open
                switch bool                   
                    case 0 % unchecked: don't show target
                        app.FigH.targetPyramidVisible('off')
                    case 1 % checked: show target
                        app.FigH.targetPyramidVisible('on')
                        app.updateTarget();
                end                
            end

           
        end

        % Button pushed function: CurrentDirectoryButton
        function CurrentDirectoryButtonPushed(app, event)
            app.datadir = uigetdir(cd, 'Select Directory to Save to');
            app.CurrentDirectoryTextArea.Value = app.datadir;
        end

        % Value changed function: TrialNumberField
        function TrialNumberFieldValueChanged(app, event)
            app.itrial = app.TrialNumberField.Value;            
        end

        % Cell edit callback: HeadFrameTable
        function HeadFrameTableCellEdit(app, event)
        
            % get location and orientation of head frame from ui table
            d = app.HeadFrameTable.Data;
            th = deg2rad(d(4:6)); % (deg -> rad)
            p = d(1:3); % (m)
            
            % adjoint matrix to transform between coordinate frames
            app.LC2Head = app.computeCoordinateTransform(p,th);            
            
        end

        % Value changed function: TargetModeDropDown
        function TargetModeDropDownValueChanged(app, event)
            app.TargetMode = str2num(app.TargetModeDropDown.Value);
            
        end

        % Value changed function: WrenchFrameDropDown
        function WrenchFrameDropDownValueChanged(app, event)
            app.FBFrameMode = str2num(app.WrenchFrameDropDown.Value);            
            
            % update LC2Head transform 
            app.HeadFrameTableCellEdit()
        end

        % Value changed function: ShowFeedbackCheckBox
        function ShowFeedbackCheckBoxValueChanged(app, event)
            bool = app.ShowFeedbackCheckBox.Value;
            
            if ~isempty(app.FigH)
                
                switch bool
                    
                    case 0 % unchecked
                        visprop = 'off';
                    case 1 % checked
                        visprop = 'on';
                end
                
                app.FigH.FBPyramidVisible(visprop);
  
            end                  
            
        end

        % Value changed function: FxCheckBox, FyCheckBox, 
        % FzCheckBox, MxCheckBox, MyCheckBox, MzCheckBox
        function ActiveCompCheckBoxValueChanged(app, event)
            app.FBCompsMat = diag([app.FxCheckBox.Value, app.FyCheckBox.Value, app.FzCheckBox.Value, ...
                                   app.MxCheckBox.Value, app.MyCheckBox.Value, app.MzCheckBox.Value]); 
            app.updateTarget(); 
            
            app.setGaugeLims();
            app.updateGaugeLims();
        end

        % Value changed function: maxFSpinner, maxMSpinner
        function maxFMValueChanged(app, event)
            
            % get values
            maxF = app.maxFSpinner.Value;
            maxM = app.maxMSpinner.Value;
            
            % copy to app property
            app.WRanges = [-maxF maxF; -maxF maxF; -maxF maxF; -maxM maxM; -maxM maxM; -maxM maxM];
            
            % update table            
            app.setGaugeLims();
            app.updateGaugeLims();
            
            
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            viewmode = app.ViewDropDown.Value;
            
            if ~isempty(app.FigH)
                app.FigH.changeCameraPosition(viewmode)
            end
            
            switch viewmode
                case {"back"}
                    app.py_scale_range = sort(app.py_scale_range,'descend');
                case {"mirror"}
                    app.py_scale_range = sort(app.py_scale_range,'ascend');
                otherwise
                    warning('invalid viewmode')
            end
            
            % need to update target because Fx scaling changed
            app.TargetSetValueChanged();
            
            
        end

        % Value changed function: TargetTransparencySpinner
        function TargetTransparencySpinnerValueChanged(app, event)
            
            if ~isempty(app.FigH)
                app.FigH.changeTPyramidTransparency(app.TargetTransparencySpinner.Value);
            end
            
        end

        % Value changed function: FBTransparencySpinner
        function FBTransparencySpinnerValueChanged(app, event)

            if ~isempty(app.FigH)
                app.FigH.changeFBPyramidTransparency(app.FBTransparencySpinner.Value);
            end
            
        end

        % Button pushed function: LoadTestDirectionsButton
        function LoadTestDirectionsButtonPushed(app, event)
            % open file dialogue
            [file,path] = uigetfile('*.mat','Select file containing Uw');
            
            if isequal(file,0)
                app.appendStatusText('User Canceled. No Uw selected');
            else       
                
                % load parameters
                try
                    S = load(fullfile(path,file));
                            
                    if isfield(S,'Uw')
                        %app.Uw = S.Uw;
                        
                        % DEBUG - CHANGE THIS WHEN FIXED W ORDER - - % 
                        app.Uw = [S.Uw(4:6,:); S.Uw(1:3,:)];
                        
                        % Switches F and M Values becuase it is backwards.
                        % END DEBUG 
                        
                        ndir = size(app.Uw,2);
                        app.DirIdxSpinner.Limits = [1 ndir];
                        app.DirIdxSpinner.Value = 1;
                        % update direction info 
                        app.DirIdxSpinnerValueChanged();
                        
                        app.appendStatusText(sprintf('Loaded Uw with %i directions',ndir));
                        
                    else
                        app.appendStatusText('Loaded file does not contain Uw field');
                    end
                    
                    if isfield(S,'dir_names')
                        app.dir_names = S.dir_names;
                    end
                    
                catch
                    app.appendStatusText('Error loading directions');
                end
                
            end
            
            % workaround for bug that makes Figure not on top anymore after
            figure(app.TargetDAQUI)
        end

        % Value changed function: DirIdxSpinner
        function DirIdxSpinnerValueChanged(app, event)
            idx = app.DirIdxSpinner.Value;
            
            if ~isempty(app.Uw)
                app.TargetTable.Data = app.Uw(:,idx);
            end
            if ~isempty(app.dir_names)
                app.DirNameField.Value = app.dir_names{idx};
            end
            
            % make sure to update target
            app.TargetSetValueChanged()
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TargetDAQUI = uifigure('Visible', 'off');
            app.TargetDAQUI.Name = 'UI Figure';
            app.TargetDAQUI.CloseRequestFcn = createCallbackFcn(app, @TargetDAQUICloseRequest, true);
            app.TabGroup = uitabgroup(app.TargetDAQUI);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.DAQConfigurationPanel = uipanel(app.SetupTab);
            app.DAQConfigurationPanel.Title = 'DAQ Configuration';
            app.DAQDeviceDropDownLabel = uilabel(app.DAQConfigurationPanel);
            app.DAQDeviceDropDownLabel.Text = 'DAQ Device';
            app.DAQDeviceDropDown = uidropdown(app.DAQConfigurationPanel);
            app.DAQDeviceDropDown.Items = {'Select a Device'};
            app.DAQDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @DAQDeviceDropDownValueChanged, true);
            app.DAQDeviceDropDown.Value = 'Select a Device';
            app.ScanRateLabel = uilabel(app.DAQConfigurationPanel);
            app.ScanRateLabel.Text = 'Scan Rate';
            app.scanRateField = uieditfield(app.DAQConfigurationPanel, 'numeric');
            app.scanRateField.Limits = [0 Inf];
            app.scanRateField.ValueChangedFcn = createCallbackFcn(app, @scanRateFieldValueChanged, true);
            app.scanRateField.Value = 1000;
            app.SampleTimeLabel = uilabel(app.DAQConfigurationPanel);
            app.SampleTimeLabel.Text = 'Sample Time';
            app.sampleTimeField = uieditfield(app.DAQConfigurationPanel, 'numeric');
            app.sampleTimeField.Limits = [0 Inf];
            app.sampleTimeField.ValueChangedFcn = createCallbackFcn(app, @sampleTimeFieldValueChanged, true);
            app.sampleTimeField.Value = 5;
            app.InitializeDAQButton = uibutton(app.DAQConfigurationPanel, 'push');
            app.InitializeDAQButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDAQButtonPushed, true);
            app.InitializeDAQButton.Text = 'Initialize DAQ';
            app.SubjectInformationPanel = uipanel(app.SetupTab);
            app.SubjectInformationPanel.Title = 'Subject Information';
            app.ParticipantIDEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDField = uieditfield(app.SubjectInformationPanel, 'text');
            app.ParticipantIDField.ValueChangedFcn = createCallbackFcn(app, @ParticipantIDFieldValueChanged, true);
            app.ProtocolEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.ProtocolEditFieldLabel.Text = 'Protocol';
            app.ProtocolField = uieditfield(app.SubjectInformationPanel, 'text');
            app.ProtocolField.ValueChangedFcn = createCallbackFcn(app, @ProtocolFieldValueChanged, true);
            app.NotesTextAreaLabel = uilabel(app.SubjectInformationPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesField = uitextarea(app.SubjectInformationPanel);
            app.NotesField.ValueChangedFcn = createCallbackFcn(app, @NotesFieldValueChanged, true);
            app.EMGOptionsPanel = uipanel(app.SetupTab);
            app.EMGOptionsPanel.Title = 'EMG Options';
            app.CollectEMGCheckBox = uicheckbox(app.EMGOptionsPanel);
            app.CollectEMGCheckBox.ValueChangedFcn = createCallbackFcn(app, @CollectEMGCheckBoxValueChanged, true);
            app.CollectEMGCheckBox.Text = 'Collect EMG';
            app.ofEMGsSpinnerLabel = uilabel(app.EMGOptionsPanel);
            app.ofEMGsSpinnerLabel.Enable = 'off';
            app.ofEMGsSpinnerLabel.Text = '# of EMGs';
            app.nEMGSpinner = uispinner(app.EMGOptionsPanel);
            app.nEMGSpinner.Limits = [0 10];
            app.nEMGSpinner.ValueChangedFcn = createCallbackFcn(app, @nEMGSpinnerValueChanged, true);
            app.nEMGSpinner.Enable = 'off';
            app.FileOptionsPanel = uipanel(app.SetupTab);
            app.FileOptionsPanel.Title = 'File Options';
            app.CurrentDirectoryButton = uibutton(app.FileOptionsPanel, 'push');
            app.CurrentDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CurrentDirectoryButtonPushed, true);
            app.CurrentDirectoryButton.Text = {'Current '; 'Directory'};
            app.CurrentDirectoryTextArea = uitextarea(app.FileOptionsPanel);
            app.basefilenameLabel = uilabel(app.FileOptionsPanel);
            app.basefilenameLabel.Text = 'base filename';
            app.basefilenameEditField = uieditfield(app.FileOptionsPanel, 'text');
            app.basefilenameEditField.Value = 'data_trial';
            app.SaveLoadPanel = uipanel(app.SetupTab);
            app.SaveLoadPanel.Title = 'Save Load';
            app.SaveConfigButton = uibutton(app.SaveLoadPanel, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.LoadConfigButton = uibutton(app.SaveLoadPanel, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.Panel = uipanel(app.SetupTab);
            app.Panel.Title = 'Panel';
            app.LoadTestDirectionsButton = uibutton(app.Panel, 'push');
            app.LoadTestDirectionsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestDirectionsButtonPushed, true);
            app.LoadTestDirectionsButton.Tooltip = {'.mat file with Uw and dir_names (cell array) variables '};
            app.LoadTestDirectionsButton.Text = 'Load Test Directions ';
            app.TransformTab = uitab(app.TabGroup);
            app.TransformTab.Title = 'Transform';
            app.BonyLandmarkTableLabel = uilabel(app.TransformTab);
            app.BonyLandmarkTableLabel.Text = 'Head Frame (in World Frame)';
            app.HeadFrameTable = uitable(app.TransformTab);
            app.HeadFrameTable.ColumnName = {''};
            app.HeadFrameTable.RowName = {'X (m)'; 'Y (m)'; 'Z (m)'; 'thX (deg)'; 'thY (deg)'; 'thZ (deg)'};
            app.HeadFrameTable.ColumnEditable = true;
            app.HeadFrameTable.CellEditCallback = createCallbackFcn(app, @HeadFrameTableCellEdit, true);
            app.XForwardYVerticalupZRightLabel = uilabel(app.TransformTab);
            app.XForwardYVerticalupZRightLabel.Text = {'X(+):  Forward'; 'Y(+):  Vertical (up)'; 'Z(+):  Right '};
            app.WrenchFrameDropDownLabel = uilabel(app.TransformTab);
            app.WrenchFrameDropDownLabel.Text = 'Wrench Frame';
            app.WrenchFrameDropDown = uidropdown(app.TransformTab);
            app.WrenchFrameDropDown.Items = {'Head', 'World', 'LoadCell'};
            app.WrenchFrameDropDown.ItemsData = {'1', '2', '3'};
            app.WrenchFrameDropDown.ValueChangedFcn = createCallbackFcn(app, @WrenchFrameDropDownValueChanged, true);
            app.WrenchFrameDropDown.Value = '1';
            app.SetTargetTab = uitab(app.TabGroup);
            app.SetTargetTab.Title = 'Set Target';
            app.TargetSettingsPanel = uipanel(app.SetTargetTab);
            app.TargetSettingsPanel.Title = 'Target Settings ';
            app.TargetDirectionLabel = uilabel(app.TargetSettingsPanel);
            app.TargetDirectionLabel.Text = 'Target Direction';
            app.TargetTable = uitable(app.TargetSettingsPanel);
            app.TargetTable.ColumnName = {'Target'};
            app.TargetTable.RowName = {'Fx '; 'Fy'; 'Fz'; 'Mx '; 'My'; 'Mz'};
            app.TargetTable.ColumnEditable = true;
            app.TargetTable.CellEditCallback = createCallbackFcn(app, @TargetSetValueChanged, true);
            app.DirIdxSpinnerLabel = uilabel(app.TargetSettingsPanel);
            app.DirIdxSpinnerLabel.Text = 'Dir Idx';
            app.DirIdxSpinner = uispinner(app.TargetSettingsPanel);
            app.DirIdxSpinner.Limits = [1 100];
            app.DirIdxSpinner.RoundFractionalValues = 'on';
            app.DirIdxSpinner.ValueDisplayFormat = '%.0f';
            app.DirIdxSpinner.ValueChangedFcn = createCallbackFcn(app, @DirIdxSpinnerValueChanged, true);
            app.DirIdxSpinner.Value = 1;
            app.DirNameField = uieditfield(app.TargetSettingsPanel, 'text');
            app.OpenTargetWindowButton = uibutton(app.SetTargetTab, 'state');
            app.OpenTargetWindowButton.ValueChangedFcn = createCallbackFcn(app, @OpenTargetWindowButtonValueChanged, true);
            app.OpenTargetWindowButton.Text = 'Open Target Window';
            app.ZeroLoadCellButton = uibutton(app.SetTargetTab, 'push');
            app.ZeroLoadCellButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroLoadCellButtonPushed, true);
            app.ZeroLoadCellButton.Text = 'Zero Load Cell';
            app.WrenchRangesPanel = uipanel(app.SetTargetTab);
            app.WrenchRangesPanel.Title = 'Wrench Ranges';
            app.maxFSpinnerLabel = uilabel(app.WrenchRangesPanel);
            app.maxFSpinnerLabel.Text = 'max F';
            app.maxFSpinner = uispinner(app.WrenchRangesPanel);
            app.maxFSpinner.Limits = [0 Inf];
            app.maxFSpinner.ValueChangedFcn = createCallbackFcn(app, @maxFMValueChanged, true);
            app.maxFSpinner.Value = 100;
            app.maxMSpinnerLabel = uilabel(app.WrenchRangesPanel);
            app.maxMSpinnerLabel.Text = 'max M';
            app.maxMSpinner = uispinner(app.WrenchRangesPanel);
            app.maxMSpinner.Limits = [0 Inf];
            app.maxMSpinner.ValueChangedFcn = createCallbackFcn(app, @maxFMValueChanged, true);
            app.maxMSpinner.Value = 15;
            app.NLabel = uilabel(app.WrenchRangesPanel);
            app.NLabel.Text = 'N';
            app.NmLabel = uilabel(app.WrenchRangesPanel);
            app.NmLabel.Text = 'N m';
            app.TargetModeDropDownLabel = uilabel(app.SetTargetTab);
            app.TargetModeDropDownLabel.Text = 'Target Mode';
            app.TargetModeDropDown = uidropdown(app.SetTargetTab);
            app.TargetModeDropDown.Items = {'Match', 'Dir and Mag'};
            app.TargetModeDropDown.ItemsData = {'1', '2'};
            app.TargetModeDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetModeDropDownValueChanged, true);
            app.TargetModeDropDown.Value = '2';
            app.ActiveComponentsPanel = uipanel(app.SetTargetTab);
            app.ActiveComponentsPanel.Title = 'Active Components';
            app.FxCheckBox = uicheckbox(app.ActiveComponentsPanel);
            app.FxCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCompCheckBoxValueChanged, true);
            app.FxCheckBox.Text = ' Fx';
            app.FxCheckBox.Value = true;
            app.FyCheckBox = uicheckbox(app.ActiveComponentsPanel);
            app.FyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCompCheckBoxValueChanged, true);
            app.FyCheckBox.Text = ' Fy';
            app.FyCheckBox.Value = true;
            app.FzCheckBox = uicheckbox(app.ActiveComponentsPanel);
            app.FzCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCompCheckBoxValueChanged, true);
            app.FzCheckBox.Text = ' Fz';
            app.FzCheckBox.Value = true;
            app.MxCheckBox = uicheckbox(app.ActiveComponentsPanel);
            app.MxCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCompCheckBoxValueChanged, true);
            app.MxCheckBox.Text = ' Mx';
            app.MxCheckBox.Value = true;
            app.MyCheckBox = uicheckbox(app.ActiveComponentsPanel);
            app.MyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCompCheckBoxValueChanged, true);
            app.MyCheckBox.Text = ' My';
            app.MyCheckBox.Value = true;
            app.MzCheckBox = uicheckbox(app.ActiveComponentsPanel);
            app.MzCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCompCheckBoxValueChanged, true);
            app.MzCheckBox.Text = ' Mz';
            app.MzCheckBox.Value = true;
            app.TargetWindowOptionsPanel = uipanel(app.SetTargetTab);
            app.TargetWindowOptionsPanel.Title = 'Target Window Options';
            app.ShowTargetCheckBox = uicheckbox(app.TargetWindowOptionsPanel);
            app.ShowTargetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTargetCheckBoxValueChanged, true);
            app.ShowTargetCheckBox.Text = 'Show Target';
            app.ShowTargetCheckBox.Value = true;
            app.ShowFeedbackCheckBox = uicheckbox(app.TargetWindowOptionsPanel);
            app.ShowFeedbackCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowFeedbackCheckBoxValueChanged, true);
            app.ShowFeedbackCheckBox.Text = 'Show Feedback';
            app.ShowFeedbackCheckBox.Value = true;
            app.ViewDropDownLabel = uilabel(app.TargetWindowOptionsPanel);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.TargetWindowOptionsPanel);
            app.ViewDropDown.Items = {'back', 'mirror'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'back';
            app.TargetTransparencySpinnerLabel = uilabel(app.TargetWindowOptionsPanel);
            app.TargetTransparencySpinnerLabel.Text = 'Target Transparency';
            app.TargetTransparencySpinner = uispinner(app.TargetWindowOptionsPanel);
            app.TargetTransparencySpinner.Step = 0.05;
            app.TargetTransparencySpinner.Limits = [0.05 1];
            app.TargetTransparencySpinner.ValueChangedFcn = createCallbackFcn(app, @TargetTransparencySpinnerValueChanged, true);
            app.TargetTransparencySpinner.Value = 0.5;
            app.FBTransparencySpinnerLabel = uilabel(app.TargetWindowOptionsPanel);
            app.FBTransparencySpinnerLabel.Text = 'FB Transparency';
            app.FBTransparencySpinner = uispinner(app.TargetWindowOptionsPanel);
            app.FBTransparencySpinner.Step = 0.05;
            app.FBTransparencySpinner.Limits = [0.05 1];
            app.FBTransparencySpinner.ValueChangedFcn = createCallbackFcn(app, @FBTransparencySpinnerValueChanged, true);
            app.FBTransparencySpinner.Value = 0.55;
            app.DirectionMatchingErrorPanel = uipanel(app.SetTargetTab);
            app.DirectionMatchingErrorPanel.Title = 'Direction Matching Error';
            app.DirectionErrorWindowLabel = uilabel(app.DirectionMatchingErrorPanel);
            app.DirectionErrorWindowLabel.Text = 'Direction Error Window';
            app.DirMatchErrorEditField = uieditfield(app.DirectionMatchingErrorPanel, 'numeric');
            app.DirMatchErrorEditField.Limits = [0 360];
            app.DirMatchErrorEditField.Value = 20;
            app.RatioErrorWindowLabel = uilabel(app.DirectionMatchingErrorPanel);
            app.RatioErrorWindowLabel.Text = 'Ratio Error Window';
            app.RatioErrorSpinner = uispinner(app.DirectionMatchingErrorPanel);
            app.RatioErrorSpinner.Step = 0.01;
            app.RatioErrorSpinner.Limits = [0 10];
            app.RatioErrorSpinner.Value = 0.1;
            app.ErrorsPanel = uipanel(app.SetTargetTab);
            app.ErrorsPanel.Title = 'Errors';
            app.MerrEditFieldLabel = uilabel(app.ErrorsPanel);
            app.MerrEditFieldLabel.Text = 'Merr';
            app.MerrEditField = uieditfield(app.ErrorsPanel, 'numeric');
            app.FerrEditFieldLabel = uilabel(app.ErrorsPanel);
            app.FerrEditFieldLabel.Text = 'Ferr';
            app.FerrEditField = uieditfield(app.ErrorsPanel, 'numeric');
            app.RerrEditFieldLabel = uilabel(app.ErrorsPanel);
            app.RerrEditFieldLabel.Text = 'Rerr';
            app.RerrEditField = uieditfield(app.ErrorsPanel, 'numeric');
            app.WrenchPlotsTab = uitab(app.TabGroup);
            app.WrenchPlotsTab.Title = 'WrenchPlots';
            app.WrenchPlotGrid = uigridlayout(app.WrenchPlotsTab);
            app.EMGPlotsTab = uitab(app.TabGroup);
            app.EMGPlotsTab.Title = 'EMGPlots';
            app.EMGPlotGrid = uigridlayout(app.EMGPlotsTab);
            app.GaugesTab = uitab(app.TabGroup);
            app.GaugesTab.Title = 'Gauges';
            app.WMagGaugeLabel = uilabel(app.GaugesTab);
            app.WMagGaugeLabel.Text = 'W Mag';
            app.WMagGauge = uigauge(app.GaugesTab, 'linear');
            app.WMagEditField = uieditfield(app.GaugesTab, 'numeric');
            app.ForcesPanel = uipanel(app.GaugesTab);
            app.ForcesPanel.Title = 'Forces';
            app.FMagEditField = uieditfield(app.ForcesPanel, 'numeric');
            app.FxMagEditField = uieditfield(app.ForcesPanel, 'numeric');
            app.FyMagEditField = uieditfield(app.ForcesPanel, 'numeric');
            app.FzMagEditField = uieditfield(app.ForcesPanel, 'numeric');
            app.FMagGauge_2Label = uilabel(app.ForcesPanel);
            app.FMagGauge_2Label.Text = 'F Mag';
            app.FMagGauge = uigauge(app.ForcesPanel, 'linear');
            app.FxGaugeLabel = uilabel(app.ForcesPanel);
            app.FxGaugeLabel.Text = 'Fx';
            app.FxGauge = uigauge(app.ForcesPanel, 'linear');
            app.FxGauge.Limits = [-1 1];
            app.FzGaugeLabel = uilabel(app.ForcesPanel);
            app.FzGaugeLabel.Text = 'Fz';
            app.FzGauge = uigauge(app.ForcesPanel, 'linear');
            app.FzGauge.Limits = [-1 1];
            app.ForwardLabel = uilabel(app.ForcesPanel);
            app.ForwardLabel.Text = 'Forward';
            app.BackwardLabel = uilabel(app.ForcesPanel);
            app.BackwardLabel.Text = 'Backward';
            app.RightLabel = uilabel(app.ForcesPanel);
            app.RightLabel.Text = 'Right';
            app.UpLabel = uilabel(app.ForcesPanel);
            app.UpLabel.Text = 'Up';
            app.DownLabel = uilabel(app.ForcesPanel);
            app.DownLabel.Text = 'Down';
            app.FyGaugeLabel = uilabel(app.ForcesPanel);
            app.FyGaugeLabel.Text = 'Fy';
            app.FyGauge = uigauge(app.ForcesPanel, 'linear');
            app.FyGauge.Limits = [-1 1];
            app.LeftLabel = uilabel(app.ForcesPanel);
            app.LeftLabel.Text = 'Left';
            app.MomentsPanel = uipanel(app.GaugesTab);
            app.MomentsPanel.Title = 'Moments';
            app.MxMagEditField = uieditfield(app.MomentsPanel, 'numeric');
            app.MyMagEditField = uieditfield(app.MomentsPanel, 'numeric');
            app.MzMagEditField = uieditfield(app.MomentsPanel, 'numeric');
            app.MMagEditField = uieditfield(app.MomentsPanel, 'numeric');
            app.MMagGaugeLabel = uilabel(app.MomentsPanel);
            app.MMagGaugeLabel.Text = 'M Mag';
            app.MMagGauge = uigauge(app.MomentsPanel, 'linear');
            app.MzGaugeLabel = uilabel(app.MomentsPanel);
            app.MzGaugeLabel.Text = 'Mz';
            app.MzGauge = uigauge(app.MomentsPanel, 'linear');
            app.MzGauge.Limits = [-1 1];
            app.MxGaugeLabel = uilabel(app.MomentsPanel);
            app.MxGaugeLabel.Text = 'Mx';
            app.MxGauge = uigauge(app.MomentsPanel, 'linear');
            app.MxGauge.Limits = [-1 1];
            app.MyGaugeLabel = uilabel(app.MomentsPanel);
            app.MyGaugeLabel.Text = 'My';
            app.MyGauge = uigauge(app.MomentsPanel, 'linear');
            app.MyGauge.Limits = [-1 1];
            app.TurnLeftLabel = uilabel(app.MomentsPanel);
            app.TurnLeftLabel.Text = 'Turn Left';
            app.TurnRightLabel = uilabel(app.MomentsPanel);
            app.TurnRightLabel.Text = 'Turn Right';
            app.SBLeftLabel = uilabel(app.MomentsPanel);
            app.SBLeftLabel.Text = 'SB Left';
            app.SBRightLabel = uilabel(app.MomentsPanel);
            app.SBRightLabel.Text = 'SB Right';
            app.LookUpLabel = uilabel(app.MomentsPanel);
            app.LookUpLabel.Text = 'Look Up';
            app.LookDownLabel = uilabel(app.MomentsPanel);
            app.LookDownLabel.Text = 'Look Down';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.StatusTextTextAreaLabel = uilabel(app.Tab);
            app.StatusTextTextAreaLabel.Text = 'Status Text';
            app.StatusText = uitextarea(app.Tab);
            app.StopButton = uibutton(app.TargetDAQUI, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.TrialEditFieldLabel = uilabel(app.TargetDAQUI);
            app.TrialEditFieldLabel.Text = 'Trial #';
            app.TrialNumberField = uieditfield(app.TargetDAQUI, 'numeric');
            app.TrialNumberField.Limits = [1 Inf];
            app.TrialNumberField.RoundFractionalValues = 'on';
            app.TrialNumberField.ValueDisplayFormat = '%.0f';
            app.TrialNumberField.ValueChangedFcn = createCallbackFcn(app, @TrialNumberFieldValueChanged, true);
            app.TrialNumberField.Value = 1;
            app.StatusLabel = uilabel(app.TargetDAQUI);
            app.StatusLabel.Text = 'Status: ';
            app.AcquireDataButton = uibutton(app.TargetDAQUI, 'push');
            app.AcquireDataButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireDataButtonValueChanged, true);
            app.AcquireDataButton.Text = 'Acquire Data';
            % Show the figure after all components are created
            app.TargetDAQUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = neckGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TargetDAQUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TargetDAQUI)
        end
    end
end