classdef plotEMGs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Panel       matlab.ui.container.Panel
        GridLayout  matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        
    end
    properties (Access = public)
        Ax                      % Array of Axes handles
        labels                  % cell array of label handles
    end
    
    methods (Access = private)
        
        function [Ax,labels] = formatGrid(app, nAx, ncol)
            
            nrows = ceil(nAx/ncol);          
            
            % Format GridLayout
            switch ncol
                case 1 % single column of axes
                    % first column is label, second column is axes
                    app.GridLayout.ColumnWidth = {'1x', '8x'};
                    % column one is labels
                    labcols = 1;
                    axcols = 2;
                case 2 % 2 columns of axes (seq left -> right, then down)
                    % 4 columns in grid, 2 labels and 2 axes
                    app.GridLayout.ColumnWidth = {'1x', '4x', '4x', '1x'};
                    % columns 1 and 4 are labels 
                    labcols = [1 4];
                    axcols = [2 3];
                otherwise
                    error('Not designed for more than 2 columns')
            end
            
            % set number of rows, all same height (so '1x') 
            rowht    = cell(1, nrows);
            rowht(:) = {'1x'};
            app.GridLayout.RowHeight = rowht;
            % compact spacing 
            app.GridLayout.ColumnSpacing = 0;
            app.GridLayout.RowSpacing = 0;
            app.GridLayout.Padding = [0 0 0 0];
            
            % Create Labels and Axes 
            aa = 1;
            for rr = 1 : nrows
                for cc = 1 : ncol
                    % create axes aa
                    Ax(aa) = uiaxes(app.GridLayout);
                    Ax(aa).Title.String = '';
                    Ax(aa).XLabel.String = '';
                    Ax(aa).YLabel.String = '';
                    Ax(aa).XColor = 'none';
                    Ax(aa).YColor = 'none';
                    Ax(aa).Layout.Row = rr;
                    Ax(aa).Layout.Column = axcols(cc);
                    
                    % create label(aa) for axes(aa)
                    labels(aa) = uilabel(app.GridLayout);
                    labels(aa).Layout.Row = rr;
                    labels(aa).Layout.Column = labcols(cc);
                    aa = aa + 1;
                end
            end  
            
        end
        
        function names = seqnames(~,N,base_name)
            % create cell array of N names with sequential numbers
            for ii = 1 : N
                names{ii} = sprintf('%s%i',base_name,ii);
            end            
        end        
        
        
        function setAxLimsEqual(~,Ax,xlims,ylims)
            % sets the x and y axis limits of all Axes in Ax array equal
            
            % dimensions
            nA = length(Ax);
            
            
            % get axes limits
            xlimits = zeros(nA,2);
            ylimits = zeros(nA,2);
            for aa = 1 : nA
                xlimits(aa,:) = Ax(aa).XLim;
                ylimits(aa,:) = Ax(aa).YLim;                
            end  
            % get max and mins of limits
            xlims_old = [min(min(xlimits)) max(max(xlimits))];
            ylims_old = [min(min(ylimits)) max(max(ylimits))];
            
            if nargin < 2      
                xlims = xlims_old;
                ylims = ylims_old;
            else
                xlims = [min([xlims_old(1) xlims(1)]) max([xlims_old(2) xlims(2)])];
                ylims = [min([ylims_old(1) ylims(1)]) max([ylims_old(2) ylims(2)])];                
            end
            
            % set all axes limits equal 
            for aa = 1 : nA
                Ax(aa).XLim = xlims;
                Ax(aa).YLim = ylims;
            end
            
        end        
        
        
    end
    
    methods (Access = public)
        
        function plotVertLine(~,Ax,t,varargin)            
            % plot a vertical line at t in all Ax included
            % can include additional arguments to change color or whatnot
            for aa = 1 : length(Ax)
                Ax(aa).NextPlot = 'add';
                xline(Ax(aa),t,varargin{:});               
            end
        end
        
        function lh = plotData(app,Ax,t,data,clearbool,varargin)
            if length(Ax) ~= size(data,2)
                error('# of axes does not match # of columns in data')
            end
            switch clearbool
                case 0
                    nextplot = 'add';
                case 1
                    nextplot = 'replacechildren';
                otherwise
                    warning('clearbool must be 0 or 1')
                    nextplot = 'add';
            end
            
            % plot data
            for aa = 1 : length(Ax)
                Ax(aa).NextPlot = nextplot;
                lh(aa) = plot(Ax(aa),t,data(:,aa),varargin{:});  
                Ax(aa).XLimMode = 'auto';
                Ax(aa).YLimMode = 'auto';
            end
            
            xlims = [min(t) max(t)];
            ylims = [min(min(data)) max(max(data))];
            
            
            % set all axes limits equal 
            app.setAxLimsEqual(Ax,xlims,ylims);   

            
        end
        
        
        function setPanelTitle(app,title_str)
            % simple wrapper to set the title of the panel 
            app.Panel.Title = title_str;
        end
        
        function setChannelNames(app, ch_names)
            % Function sets the label text next to each axes according to
            % strings provided in ch_names
            % ch_names: cell array of channel names equal to number of axes
            
            % dimensions
            nLabels = length(app.labels);
            nNames = length(ch_names);
            
            % check that there aren't too few names provided
            if nNames ~= nLabels
                warning('# of channel names does not match # of axes')
                ch_names = app.seqnames(nLabels,'ch');                
            end
            
            % set label text to provided names 
            for ii = 1 : nLabels
                app.labels(ii).Text = ch_names{ii};
            end
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, nAx, ncol, ch_names)
            % INPUTS:   nAx: scalar, number of axes
            %           ncol: number of columns (only 1 or 2)
            %           ch_names: cell array of strings 
            
            
            % Check Inputs
            if nargin < 2
                error('Must include number of axes as input')
            elseif nargin < 3
                ncol = 2; %default
                ch_names = app.seqnames(nAx,'ch');
            elseif nargin < 4
                ch_names = app.seqnames(nAx,'ch'); 
            else
                if length(ch_names) < nAx
                   ch_names = app.seqnames(nAx,'ch');
                   warning('not enough channel names provided, renamed')
                end                
            end
           

            % format the UIGrid and get handles to axes and labels 
            [app.Ax,app.labels] = app.formatGrid(nAx,ncol);
            
            app.setChannelNames(ch_names);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotEMGs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end